classdef appMonitorLicorEC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MicrometSiteInstrumentsUIFigure  matlab.ui.Figure
        LICORECMonitorLabel  matlab.ui.control.Label
        UpdateAllButton      matlab.ui.control.Button
        LastdataupdatePanel  matlab.ui.container.Panel
        LastUpdateLabel      matlab.ui.control.Label
        TabGroup             matlab.ui.container.TabGroup
        Site_1               matlab.ui.container.Tab
    end

    % appMonitorLicorEC(projectPath)
    % 
    %   - Collects data directly from LI-7x00 instruments over Ethernet
    %   - The instruments should be set to output all data over Ethernet at 1Hz 
    %     (not faster and not slower than this)
    %   - Calculates half houry statistics. Stores raw data and database files 
    %     in the usual "Sites" and "Database" folders.
    %   - requires the input argument projectPath. This path should point
    %     to the main project folder (the folder containing "Sites" and "Database" folders.
    %     On UQAM    server: projectPath = "c:\Projects\UQAM"
    %     On vinimet server: projectPath = "p:\"
    %     On PAOA001 server: projectPath = "d:\"
    %     (for testing: server: projectPath = "d:\junk")
    %   - The program expects access to (has to have on the Matlab path) a "get_TAB_project_configuration.m" file containing all input parameters.
    %     In the future this will read a proper YAML file ("projectUQAM_config.yaml")
    % 
    %    Example of a minimum get_TAB_project_configuration.m file:
    %       function structProject = get_TAB_project_configuration(projectPath)
    %       % This function will be replaced with a single config.yml file
    %       % in the projectPath folder that the parent function will read.
    %       %
    %       % To simplify writing of YAML config files and reduce cutting and pasting
    %       % errors, we could write a Matlab GUI to create the initial YAML using the code
    %       % below. The resulting YAML can then be
    %       % edited by either the same GUI or by the user using a text editor.
    %       % 
    %       % Here is the matlab structure structProject that could be saved
    %       % as YAML file using yaml.dumpFile function.
    %       %-------- start yaml ---------------------
    %       projectName = '';               % needs to be an empty string for vinimet and PAOA001. 
    %                                       % For all new sites should be the actual project name
    %                                       % Examples: 
    %                                       % UAQM:
    %                                       %    projectPath = 'c:\Projects'
    %                                       %    projectName = 'UQAM'
    %                                       % McGill's version of 'Micromet':
    %                                       %    projectPath = 'c:\Projects'
    %                                       %    projectName = 'Micromet'
    % 
    %       structProject.projectName   = projectName;
    %       structProject.path          = fullfile(projectPath,structProject.projectName);
    %       structProject.databasePath  = fullfile(structProject.path,'Database');
    %       structProject.sitesPath     = fullfile(structProject.path,'Sites');
    %       structProject.matlabPath    = fullfile(structProject.path,'Matlab');
    % 
    %       siteID = 'BB1';
    %       structProject.sites.(siteID).siteIP = "173.181.138.49";

    % Revisions
    %
    % Aug 4, 2025 (Zoran)
    %   - Bug fix: When testing for out-of-range variables the flags should be tested first "flagLI7200 &&" not last
    %   - New feature: When converting output HH structure to database, change the TimeVector from local (PC server) time
    %                  zone to the site's standard time (as given in siteID_config.yml file)
    %   - New feature: The site tab will be highllighted in Red if SFVin < 11 V which indicates locked up SmartFlux.
    % Jul 22, 2025 (Zoran)
    %   - Added an option to setup in the project configuration file fields identifying
    %     which LICOR instruments are used at each site so if an instrument is not used
    %     the program does not report a site-warning (yello-wish tab font)
    %     Use this in the get_TAB_configuration file:       
    %        % Flux instruments
    %       structProject.sites.(siteID).dataSources.flux.LI7200 = true;
    %       structProject.sites.(siteID).dataSources.flux.LI7500 = false;
    %       structProject.sites.(siteID).dataSources.flux.LI7700 = false;
    % Aug 11, 2024 (Zoran)
    %   - Moved away from db_new_eddy (which had a bug that showed up in Matlab 2024a)
    %     to db_struct2database.
    % July 30, 2024 (Zoran)
    %  - Fixed a bug. The app was not checking if the destination hhour folder 
    %    existed before trying to store the data there. It now creates the folder
    %    if needed.

    properties (Access = private)
        aVariable           % Description
        timerMain           % main timer object that initiates data collection
        projectPath         % path to the project's folder
        siteInfo            % data structure with site names and IP addresses (loaded from a yml file)
        siteData            % data structure with all data for the current 30-min period
        sampleNum = 0;      % current data point being acquired
        tvLastSample = 0;   % datenum of the last data point  
        samplingPeriodSec   % sampling period
        outputFileNameHF = "monitorSiteLI7x00_HFdata.%s.mat";      %
        outputPathHF      % High frequency data path name
        outputFileNameHH = "monitorSiteLI7x00_hhour.%s.mat";      %
        outputPathHH      % Half hour summaries path name
        outputPathDB      % output path for the data base
        delayCalling = 20 % if running tests on my PC offset the timer by 20 seconds to avoid 
                          % overlapping the calls between my PC and the main server at vinimet
    end
    
    methods (Access = private)
        %================================
        % Download data, update all tabs
        %================================
        function results = downloadData(app,siteName,siteAddress,sitePortNum)
            
            arg_default('sitePortNum',7200);
            
            % Import project configuration from a YAML file
            structProject = get_project_configuration(app,app.projectPath);
            
            % if the field "flux" is missing under dataSources set all instrument
            % flags to true. This should provide compatibility with the sites that
            % don't have their project configuration with this info
            if ~isfield(structProject.sites.(siteName).dataSources,'flux')
                flagLI7200 = true;
                flagLI7700 = true;
                flagLI7500 = true;
            else
                flagLI7200 = isfield(structProject.sites.(siteName).dataSources,'flux') && ...
                             isfield(structProject.sites.(siteName).dataSources.flux,'LI7200') && ...
                                     structProject.sites.(siteName).dataSources.flux.LI7200;
                flagLI7500 = isfield(structProject.sites.(siteName).dataSources,'flux') && ...
                             isfield(structProject.sites.(siteName).dataSources.flux,'LI7500') && ...
                                     structProject.sites.(siteName).dataSources.flux.LI7500;
                flagLI7700 = isfield(structProject.sites.(siteName).dataSources,'flux') && ...
                             isfield(structProject.sites.(siteName).dataSources.flux,'LI7700') && ...
                                     structProject.sites.(siteName).dataSources.flux.LI7700;            
            end

            cntSites = find(strcmpi({app.siteInfo(:).name},siteName));
            currentTab = app.siteInfo(cntSites).tabHandle;
            try
                [dataOut,cError,allData] = fr_read_TCP_LI7x00_data(siteAddress,sitePortNum,5);
                app.siteData(app.sampleNum).(siteName) = dataOut; 

                if isempty(cError)
                    currentTab.ForegroundColor = [0 0 0];
                    currentData = app.siteData(app.sampleNum).(siteName);
                    % Convert siteName.TimeVector to standard time
                    app.siteData(app.sampleNum).(siteName).TimeVector = pcTime2standardTime(app,app.siteData(app.sampleNum).(siteName).TimeVector,cntSites);
                    fprintf('%s\n',datestr(app.siteData(app.sampleNum).(siteName).TimeVector));
                    allFields = fieldnames(currentData);
                    outputString = sprintf('Sample #%d for this half hour.\nCollected at: %s (site standard time)\n\n',app.sampleNum,datestr(currentData.TimeVector,31));
                    for cntFields = 1:length(allFields)
                        currentFieldName = char(allFields(cntFields));
                        dataValue = app.siteData(app.sampleNum).(siteName).(currentFieldName);
                        % test if any data value is out of range
                        errorIndicator = '';
                        switch currentFieldName
                            case 'Diag'                                
                                if dataValue == 255 && isempty(dataOut.flowDrive)
                                    % case LI7500, diagnostic value.
                                    errorIndicator = ' *';
                                    currentTab.ForegroundColor = [1 1 0];
                                elseif dataValue < 8190 && ~isempty(dataOut.flowDrive)
                                    % case LI7200, low diagnostic value
                                    errorIndicator = ' *';
                                    currentTab.ForegroundColor = [1 1 0];                                
                                end
                            case 'flowRate' 
                                if flagLI7200 && dataValue < 13
                                    errorIndicator = ' *';
                                    currentTab.ForegroundColor = [1 1 0];
                                end
                            case 'flowDrive' 
                                if flagLI7200 && dataValue > 90
                                    errorIndicator = '>90%';
                                    currentTab.ForegroundColor = [1 1 0];
                                end
                            case {'tempOut','tempIn'}
                                if ~isempty(dataValue) && ~isempty(dataOut.tempOut) && abs(dataOut.tempOut-dataOut.tempIn) > 3 && flagLI7200
                                    errorIndicator = 'Tin-Tout>3';
                                    currentTab.ForegroundColor = [1 1 0];
                                end
                            case 'signalStrength7700'
                                if flagLI7700 && dataValue < 20
                                    errorIndicator = ' <20';
                                    currentTab.ForegroundColor = 0.7*[1 1 0];
                                end          
                            case 'Phead'
                                if flagLI7200 && dataValue < -3
                                    errorIndicator = ' <-3';
                                    currentTab.ForegroundColor = [1 1 0];
                                end     
                            case 'SFVin'
                                if dataValue < 11
                                    errorIndicator = ' < 11 Volts';
                                    currentTab.ForegroundColor = [1 0 0];
                                end                                      
                        end
                        outputString = [outputString sprintf('%25s = %14.2f %s\n',currentFieldName,dataValue,errorIndicator)];
                    end
                    app.siteInfo(cntSites).textArea1.Value = outputString;
                else
                    currentTab.ForegroundColor = [1 0 0];
                    app.siteInfo(cntSites).textArea1.Value = [cError allData];                    
                end              

            catch ME
                currentTab.ForegroundColor = [1 0 0];
                disp(ME);
                fprintf('*ERROR for %s site.\n',siteName);
                %disp(dataOut)
            end
            drawnow
        end
        
       
        function mainTimerCallback(app, ~, event)
            UpdateAllButtonPushed(app,event)
        end
        
        %==================================================
        % get_project_configuration
        %
        % Load up the project configuration and remove
        % the sites that don't have LI-7200 or LI-7500
        %==================================================
        function structProject = get_project_configuration(app,projectPath)
            structProject = get_TAB_project_configuration(projectPath);
            % filter out the sites that don't have either 7200 or 7500
            strAllSiteNames = fieldnames(structProject.sites);                         
            for cntSiteName = 1:length(strAllSiteNames)
                strSiteName = char(strAllSiteNames{cntSiteName});
                if ~isfield(structProject.sites.(strSiteName).dataSources,'flux') ...
                        || (   ~structProject.sites.(strSiteName).dataSources.flux.LI7200 ...
                            && ~structProject.sites.(strSiteName).dataSources.flux.LI7500) 
                    % Skip this site
                    structProject.sites = rmfield(structProject.sites,strSiteName);
                end
            end                
        end


        % ==================================================
        % halfHourEvent
        %
        % Calculates stats, stores HF and HH data
        %===================================================
        function halfHourEvent(app,currentHHour)          
            % Save current data into dataHF
            dataHF = app.siteData;
            
            % save high frequency data using currentHHour (need an
            % end-of-period time stamp)
            fileFolder = fullfile(app.outputPathHF,datestr(currentHHour,'yyyymmdd'));
            if ~exist(fileFolder,"dir")
                mkdir(fileFolder)                     
            end
            fileName = sprintf(app.outputFileNameHF,datestr(currentHHour,'yyyymmddHHMM'));
            filePathHF = fullfile(fileFolder,fileName);
            save(filePathHF,'dataHF')
            
            % clean data buffer
            app.siteData = [];
            app.sampleNum = 0;            
            
            try
                %% calculate hhourly stats            
                % cycle through all sites
                strAllSiteNames = fieldnames(dataHF);             
                for cntSiteName = 1:length(strAllSiteNames)
                    strSiteName = char(strAllSiteNames{cntSiteName});
                    % pick only site names, skip TimeVector
                    if ~strcmpi(strSiteName,"TimeVector")     
                        % now get all variables that need averaging
                        strAllVariables = fieldnames(dataHF(1).(strSiteName));
                        for cntVarName = 1:length(strAllVariables)
                            strVarName = char(strAllVariables{cntVarName});
                            dataIn = get_stats_field_fast(dataHF,[strSiteName '.' strVarName]);                        
                            if ~strcmpi(strVarName,"TimeVector")
                                tmpStats.(strSiteName).(strVarName).avg = mean(dataIn(~isnan(dataIn)));
                                tmpStats.(strSiteName).(strVarName).max = max(dataIn(~isnan(dataIn)));
                                tmpStats.(strSiteName).(strVarName).min = min(dataIn(~isnan(dataIn)));
                                tmpStats.(strSiteName).(strVarName).std = std(dataIn(~isnan(dataIn)));
                            else
                                % Store the last time stamp collected from the instrument
                                % as datenum, but store the time only (no date)
                                tmpStats.(strSiteName).Time = dataIn(end)-floor(dataIn(end));
                                % Store also the TimeVector 
                                tmpStats.(strSiteName).TimeVector = currentHHour;
                                % Calculate stats on the sampling rate (how often is data collected)
                                tmpSampleRate = diff(dataIn *24*3600);
                                if ~isempty(tmpSampleRate)
                                    tmpStats.(strSiteName).SampleRate.avg = mean(tmpSampleRate); %#ok<*AGROW> 
                                    tmpStats.(strSiteName).SampleRate.max = max(tmpSampleRate);
                                    tmpStats.(strSiteName).SampleRate.min = min(tmpSampleRate);
                                    tmpStats.(strSiteName).SampleRate.std = std(tmpSampleRate);
                                else
                                    tmpStats.(strSiteName).SampleRate.avg = NaN; 
                                    tmpStats.(strSiteName).SampleRate.max = NaN;
                                    tmpStats.(strSiteName).SampleRate.min = NaN;
                                    tmpStats.(strSiteName).SampleRate.std = NaN;      
                                end
                            end
                        end
                        tmpStats.(strSiteName).numOfSamples = length(dataIn);
                    end
                end
                % Add TimeVector to the main structure
                tmpStats.TimeVector = currentHHour;
                % save half hourly stats using currentHHour (need an
                % end-of-period time stamp). 
                fileName = sprintf(app.outputFileNameHH,datestr(currentHHour,'yyyymmdd'));
                filePathHH = fullfile(app.outputPathHH,fileName);
                % check if the file with the same name already exists there
                if exist(filePathHH,'file')
                    load(filePathHH,'Stats');
                    numRec = length(Stats);
                else
                    numRec = 0;
                end
                numRec = numRec+1;
                % append the new record to the existing ones
                Stats(numRec) = tmpStats;
                % save hhour stats
                % first make sure the app.outputPathHH folder exists.
                if ~exist(app.outputPathHH,"dir")
                    mkdir(app.outputPathHH);
                end
                save(filePathHH,'Stats');
                % also save the data into database (only the last record)
                Stats = tmpStats;
                % Collect all site names
                allSites = {};
                for cntSite = 1:length(app.siteInfo)
                    allSites{cntSite} = app.siteInfo(cntSite).name;
                end
                %allSites = {'BB1','BB2','DSM','RBM','HOGG','YOUNG','OHM'};
                for cntSite = 1:length(allSites)
                    currentSite = allSites{cntSite};
                    % when processing data exclude all the sites except the currentSite.
                    excludeSites = setdiff(allSites,currentSite);
                    if strcmpi(currentSite,'BB1')
                        siteName = 'BB';
                    else
                        siteName = currentSite;
                    end
                    % append the year to outputPathDB
                    dbasePath = fullfile(app.outputPathDB,datestr(currentHHour,'yyyy')); %#ok<*DATST>
                    % convert Stats.TimeVector that's server time to the actual site timeZone 
                    % so the data in the data base matches time stamps from the site's data 
                    Stats.TimeVector = pcTime2standardTime(app,Stats.TimeVector,cntSite);
                    % tv_dt = datetime(Stats.TimeVector,'convertfrom','datenum','TimeZone','Local');  % TimeVector -> datetime (local time zone)
                    % tv_dt.TimeZone = 'UTC';  % datetime local -> UTC
                    % tvUTC = datenum(tv_dt);     % datetime UTC -> datenum UTC
                    % Stats.TimeVector = tvUTC + app.siteInfo(cntSite).TimeZoneHour/24; % convert to site's standard time
                    % Stats.(currentSite).TimeVector = Stats.TimeVector*ones(size(Stats.(currentSite).TimeVector));
                    db_struct2database(Stats,fullfile(dbasePath,siteName,'monitorSites'),0,excludeSites,[],NaN,[],[]);
                end
            catch ME
                % If an error, skip hhour calculations
                % Display the error message for troubleshooting purposes.
                disp(ME);
            end       
        end % function halfHourEvent

            %% Convert datenum time from sever time to the site standard time
            function tv_Standard = pcTime2standardTime(app,tv_PC,cntSite)
                % input tv_PC is a datenum
                % convert it first to local (PC) time
                tv_dt_UTC = datetime(tv_PC,'convertfrom','datenum','TimeZone','Local');
                % then convert it to UTC
                tv_dt_UTC.TimeZone = 'UTC';
                % then to Standard datetime
                tv_DT_Standard = tv_dt_UTC + app.siteInfo(cntSite).TimeZoneHour/24; % convert to site's standard time
                % then to datenum
                tv_Standard = datenum(tv_DT_Standard); %#ok<*DATNM>
            end
        end % methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, projectPath)
            
            % Return if project path is not defined
            if ~exist("projectPath","var") || isempty(projectPath)
                msgbox("Please set the input argument: projectPath!","non-modal");
                MicrometSiteInstrumentsUIFigureCloseRequest(app)
            end

            app.projectPath = projectPath;

            % Import site names and IP addresses from a YAML file
            structProject = get_project_configuration(app,projectPath);
            
            % Keep only sites that have LI-7200 or LI-7500 running

            % Check which PC is app running on (ENG0x or vinimet) and
            if strcmpi(fullfile(projectPath),fullfile('D:\junk\test_monitorSiteLI7x00'))
                app.delayCalling = 20;      % offset calling sites by 20 seconds to avoid overlap with vinimet
                app.samplingPeriodSec = 60; % faster sampling during app design
            else
                app.delayCalling = 0;
                app.samplingPeriodSec = 180;
            end
            app.outputPathHF = fullfile(structProject.sitesPath,'Common\monitorSiteLI7x00\Data');
            app.outputPathHH = fullfile(structProject.sitesPath,'Common\monitorSiteLI7x00\hhour');
            app.outputPathDB = structProject.databasePath;            

            % Cycle through all sites
            %   - load up the siteInfo from YAML file
            %   - Name all tabs
            allSiteNames = fieldnames(structProject.sites);
            for cntSites = 1:length(allSiteNames)
                app.siteInfo(cntSites).name = char(allSiteNames(cntSites));
                % 
                % Load site names and IP addresses
                app.siteInfo(cntSites).IPaddress = structProject.sites.(app.siteInfo(cntSites).name).siteIP;                
                if isfield(structProject.sites.(app.siteInfo(cntSites).name),'Metadata')
                    app.siteInfo(cntSites).TimeZoneHour = structProject.sites.(app.siteInfo(cntSites).name).Metadata.TimeZoneHour;
                else
                    fprintf(2,'Metadata TimeZoneHour from siteID_config.yml does not exist. Using -8 hours.\n');
                    app.siteInfo(cntSites).TimeZoneHour = -8;
                end
                % create a new tab
                if cntSites == 1
                    currentTab = app.TabGroup.Children;
                else
                    currentTab = uitab(app.TabGroup,'Title',app.siteInfo(cntSites).name);
                end
                app.siteInfo(cntSites).tabHandle = currentTab;
                currentTab.Title = app.siteInfo(cntSites).name;
                tabPos = currentTab.Position;
                app.siteInfo(cntSites).textArea1 = uitextarea(currentTab,Position=[tabPos(1:2)+2 400 380],Editable = 'off',Fontname = 'Consolas');
                app.siteInfo(cntSites).textArea1.HorizontalAlignment = 'left';
                app.siteInfo(cntSites).textArea1.Value = [sprintf('%30s = %6.2f\n','co2',555-cntSites) ,sprintf('%30s = %6.2f\n','SignalStrength',100-cntSites/5)];
                app.siteInfo(cntSites).textArea1.FontColor = [0 0 0];

            end
            
            % updated data once to populate the table
            UpdateAllButtonPushed(app)
            
            % stop the timer function if already running
            t = timerfind('Name','siteUpdateTimer');
            if ~isempty(t)
                stop(t)
                delete(t)
            end
            
            % calculate delay time so the sampling time can be aligned to
            % sample at 0 seconds into samplingPeriodSec
            tv = now; %#ok<*TNOW1>
            samplesPerDay = 24*3600/app.samplingPeriodSec;         
            nextPeriod = ceil(tv*samplesPerDay)/samplesPerDay;
            delayTime = round((nextPeriod-tv)*24*3600) + app.delayCalling;  % delay time in seconds
            % create a timer that will update data every
            % 5 minutes (300s). 
            % to speedup debugging, run the timer faster (once every 60 seconds)
            app.timerMain = timer( "timerfcn",@app.mainTimerCallback,...
                                   "StartDelay",delayTime,...
                                   "Period",app.samplingPeriodSec,...
                                   "ExecutionMode",'fixedRate',...
                                   "Name",'siteUpdateTimer');
            % then start the timerMain
            start(app.timerMain)
        end

        % Button pushed function: UpdateAllButton
        function UpdateAllButtonPushed(app, event)

            % Check if this is a new 30-min period and,
            % if it is, store the collected data (if there is any)
            if app.sampleNum > 0
                % check if this is a new 30min period
                lastHHour = fix(app.siteData(app.sampleNum).TimeVector*48)/48;
                currentHHour = fix(now*48)/48;
                if lastHHour < currentHHour
                    % calculate hhourly stats
                    % calculate stats for all variables for each site
                    % store high frequency and half-hourly values
                    halfHourEvent(app,currentHHour)
                end
                
            end
                
            % There is a new data sample being collected. Increment counter
            app.sampleNum = app.sampleNum + 1;
            app.siteData(app.sampleNum).TimeVector = now;
            app.LastUpdateLabel.Text = datestr(now,31);

            % Gray out all the tabs to indicate that an update is in progress
            for cntSites = 1:length(app.siteInfo)
                currentTab = app.siteInfo(cntSites).tabHandle;
                currentTab.ForegroundColor = [0.5 0.5 0.5];
            end
            % download data a update tabs
            for cntSites = 1:length(app.siteInfo)
                downloadData(app,app.siteInfo(cntSites).name,app.siteInfo(cntSites).IPaddress);
            end
            
        end

        % Close request function: MicrometSiteInstrumentsUIFigure
        function MicrometSiteInstrumentsUIFigureCloseRequest(app, event)
            if isobject("app.timerMain")
                stop(app.timerMain) %#ok<UNRCH>
                delete(app.timerMain)
                clear app.timerMain
            end
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MicrometSiteInstrumentsUIFigure = uifigure('Visible', 'off');
            app.MicrometSiteInstrumentsUIFigure.Name = 'Micromet Site Instruments';
            app.MicrometSiteInstrumentsUIFigure.CloseRequestFcn = createCallbackFcn(app, @MicrometSiteInstrumentsUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.MicrometSiteInstrumentsUIFigure);
            app.Site_1 = uitab(app.TabGroup);
            app.LastdataupdatePanel = uipanel(app.MicrometSiteInstrumentsUIFigure);
            app.LastdataupdatePanel.Title = 'Last data update:';
            app.LastUpdateLabel = uilabel(app.LastdataupdatePanel);
            app.LastUpdateLabel.Text = '';
            app.UpdateAllButton = uibutton(app.MicrometSiteInstrumentsUIFigure, 'push');
            app.UpdateAllButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateAllButtonPushed, true);
            app.UpdateAllButton.Enable = 'off';
            app.UpdateAllButton.Visible = 'off';
            app.UpdateAllButton.Text = 'Update All';
            app.LICORECMonitorLabel = uilabel(app.MicrometSiteInstrumentsUIFigure);
            app.LICORECMonitorLabel.Text = 'LICOR EC Monitor';
            % Show the figure after all components are created
            app.MicrometSiteInstrumentsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMonitorLicorEC(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MicrometSiteInstrumentsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.MicrometSiteInstrumentsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MicrometSiteInstrumentsUIFigure)
        end
    end
end