classdef appMonitorSiteLI7x00 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MicrometSiteInstrumentsUIFigure  matlab.ui.Figure
        LI7x00MonitoringAppLabel  matlab.ui.control.Label
        UpdateAllButton           matlab.ui.control.Button
        LastdataupdatePanel       matlab.ui.container.Panel
        LastUpdateLabel           matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        GridLayout                matlab.ui.container.GridLayout
        OHM_LastError             matlab.ui.control.Label
        OHM_Diag7700Label         matlab.ui.control.Label
        OHM_SS7700Label           matlab.ui.control.Label
        OHM_CH4Label              matlab.ui.control.Label
        OHM_H2OLabel              matlab.ui.control.Label
        OHM_CO2Label              matlab.ui.control.Label
        OHM_PairLabel             matlab.ui.control.Label
        OHM_PheadLabel            matlab.ui.control.Label
        OHM_ToutLabel             matlab.ui.control.Label
        OHM_TinLabel              matlab.ui.control.Label
        OHM_FlowDriveLabel        matlab.ui.control.Label
        OHM_FlowLabel             matlab.ui.control.Label
        OHM_Diag                  matlab.ui.control.Label
        OHMLabel                  matlab.ui.control.Label
        OHM_Lamp                  matlab.ui.control.Lamp
        YOUNG_LastError           matlab.ui.control.Label
        HOGG_LastError            matlab.ui.control.Label
        RBM_LastError             matlab.ui.control.Label
        DSM_LastError             matlab.ui.control.Label
        Label_20                  matlab.ui.control.Label
        BB2_LastError             matlab.ui.control.Label
        BB1_LastError             matlab.ui.control.Label
        Label_15                  matlab.ui.control.Label
        CH4Label                  matlab.ui.control.Label
        Signal7700Label           matlab.ui.control.Label
        Diag7700Label             matlab.ui.control.Label
        H2OLabel                  matlab.ui.control.Label
        CO2_dryLabel              matlab.ui.control.Label
        PairLabel                 matlab.ui.control.Label
        PheadLabel                matlab.ui.control.Label
        ToutLabel                 matlab.ui.control.Label
        TinLabel                  matlab.ui.control.Label
        FlowDriveLabel            matlab.ui.control.Label
        FlowLabel                 matlab.ui.control.Label
        DiagIRGALabel             matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        YOUNG_CH4Label            matlab.ui.control.Label
        YOUNG_FlowLabel           matlab.ui.control.Label
        YOUNG_FlowDriveLabel      matlab.ui.control.Label
        YOUNG_TinLabel            matlab.ui.control.Label
        YOUNG_ToutLabel           matlab.ui.control.Label
        YOUNG_PheadLabel          matlab.ui.control.Label
        YOUNG_PairLabel           matlab.ui.control.Label
        YOUNG_CO2Label            matlab.ui.control.Label
        YOUNG_H2OLabel            matlab.ui.control.Label
        YOUNG_Diag7700Label       matlab.ui.control.Label
        YOUNG_SS7700Label         matlab.ui.control.Label
        YOUNG_Diag                matlab.ui.control.Label
        HOGG_SS7700Label          matlab.ui.control.Label
        HOGG_Diag7700Label        matlab.ui.control.Label
        HOGG_H2OLabel             matlab.ui.control.Label
        HOGG_CO2Label             matlab.ui.control.Label
        HOGG_PairLabel            matlab.ui.control.Label
        HOGG_PheadLabel           matlab.ui.control.Label
        HOGG_ToutLabel            matlab.ui.control.Label
        HOGG_TinLabel             matlab.ui.control.Label
        HOGG_FlowDriveLabel       matlab.ui.control.Label
        HOGG_FlowLabel            matlab.ui.control.Label
        HOGG_CH4Label             matlab.ui.control.Label
        HOGG_Diag                 matlab.ui.control.Label
        RBM_SS7700Label           matlab.ui.control.Label
        RBM_Diag7700Label         matlab.ui.control.Label
        RBM_H2OLabel              matlab.ui.control.Label
        RBM_CO2Label              matlab.ui.control.Label
        RBM_PairLabel             matlab.ui.control.Label
        RBM_PheadLabel            matlab.ui.control.Label
        RBM_ToutLabel             matlab.ui.control.Label
        RBM_TinLabel              matlab.ui.control.Label
        RBM_FlowDriveLabel        matlab.ui.control.Label
        RBM_FlowLabel             matlab.ui.control.Label
        RBM_CH4Label              matlab.ui.control.Label
        RBM_Diag                  matlab.ui.control.Label
        DSM_SS7700Label           matlab.ui.control.Label
        DSM_Diag7700Label         matlab.ui.control.Label
        DSM_H2OLabel              matlab.ui.control.Label
        DSM_CO2Label              matlab.ui.control.Label
        DSM_PairLabel             matlab.ui.control.Label
        DSM_PheadLabel            matlab.ui.control.Label
        DSM_ToutLabel             matlab.ui.control.Label
        DSM_TinLabel              matlab.ui.control.Label
        DSM_FlowDriveLabel        matlab.ui.control.Label
        DSM_FlowLabel             matlab.ui.control.Label
        DSM_CH4Label              matlab.ui.control.Label
        DSM_Diag                  matlab.ui.control.Label
        BB2_CH4Label              matlab.ui.control.Label
        BB2_SS7700Label           matlab.ui.control.Label
        BB2_Diag7700Label         matlab.ui.control.Label
        BB2_H2OLabel              matlab.ui.control.Label
        BB2_CO2Label              matlab.ui.control.Label
        BB2_PairLabel             matlab.ui.control.Label
        BB2_PheadLabel            matlab.ui.control.Label
        BB2_ToutLabel             matlab.ui.control.Label
        BB2_TinLabel              matlab.ui.control.Label
        BB2_FlowDriveLabel        matlab.ui.control.Label
        BB2_FlowLabel             matlab.ui.control.Label
        BB2_Diag                  matlab.ui.control.Label
        BB1_CH4Label              matlab.ui.control.Label
        BB1_SS7700Label           matlab.ui.control.Label
        BB1_Diag7700Label         matlab.ui.control.Label
        BB1_H2OLabel              matlab.ui.control.Label
        BB1_CO2Label              matlab.ui.control.Label
        BB1_PairLabel             matlab.ui.control.Label
        BB1_PheadLabel            matlab.ui.control.Label
        BB1_ToutLabel             matlab.ui.control.Label
        BB1_TinLabel              matlab.ui.control.Label
        BB1_FlowDriveLabel        matlab.ui.control.Label
        BB1_FlowLabel             matlab.ui.control.Label
        BB1_Diag                  matlab.ui.control.Label
        umolmolLabel_2            matlab.ui.control.Label
        Label_19                  matlab.ui.control.Label
        Label_18                  matlab.ui.control.Label
        kPaLabel_2                matlab.ui.control.Label
        kPaLabel                  matlab.ui.control.Label
        mmolmolLabel              matlab.ui.control.Label
        umolmolLabel              matlab.ui.control.Label
        YOUNG_Lamp                matlab.ui.control.Lamp
        HOGG_Lamp                 matlab.ui.control.Lamp
        RBM_Lamp                  matlab.ui.control.Lamp
        DSM_Lamp                  matlab.ui.control.Lamp
        BB2_Lamp                  matlab.ui.control.Lamp
        YOUNGLabel                matlab.ui.control.Label
        HOGGLabel                 matlab.ui.control.Label
        RBMLabel                  matlab.ui.control.Label
        DSMLabel                  matlab.ui.control.Label
        degCLabel_2               matlab.ui.control.Label
        degCLabel                 matlab.ui.control.Label
        BB2Label                  matlab.ui.control.Label
        Label_16                  matlab.ui.control.Label
        slpmLabel                 matlab.ui.control.Label
        DiagLabel                 matlab.ui.control.Label
        Label_17                  matlab.ui.control.Label
        BB1Label                  matlab.ui.control.Label
        BB1_Lamp                  matlab.ui.control.Lamp
    end

    % Revisions
    %
    % Jan 21, 2024 (Zoran)
    %   - found bug causing to have min values set to 0 when an element of the structure was set to [];
    %     The problem is with get_stats_field function. For the input:
    %      x(1).a=1;x(2).a=2;x(3).a=[];
    %     it returns:
    %       >> get_stats_field(x,'a')'  
    %       >>   1     2     0
    %     instead of 
    %       >> get_stats_field_fast(x,'a')'
    %       >>  1     2   NaN
    %      Replaced with get_stats_field_fast.
    % Jan 8, 2024 (Zoran)
    %   - found a bug. I had:
    %       exist('p:\database','dir')
    %     instead of:
    %      ~exist('p:\database','dir')
    %
    properties (Access = private)
        aVariable           % Description
        timerMain           % main timer object that initiates data collection
        siteData            % data structure with all data for the current 30-min period
        sampleNum = 0;      % current data point being acquired
        tvLastSample = 0;   % datenum of the last data point  
        samplingPeriodSec   % sampling period
        outputFileNameHF = "monitorSiteLI7x00_HFdata.%s.mat";      %
        outputPathHF      % High frequency data path name
        outputFileNameHH = "monitorSiteLI7x00_hhour.%s.mat";      %
        outputPathHH      % Half hour summaries path name
        outputPathDB      % output path for the data base
        delayCalling = 20 % if running tests on my PC offset the timer by 20 seconds to avoid 
                          % overlapping the calls between my PC and the main server at vinimet
    end
    
    methods (Access = private)
        
        function results = downloadData(app,siteName,siteAddress,sitePortNum)
            arg_default('sitePortNum',7200);
            try
                clean_site_data(app,siteName);
                [dataOut,cError,allData] = fr_read_TCP_LI7x00_data(siteAddress,sitePortNum,5);
                app.siteData(app.sampleNum).(siteName) = dataOut; 
                ledColor = [0 1 0];
                if isempty(cError)
                    %app.(sprintf('%s_LastError',siteName)).Text = "OK!";
                    app.(sprintf('%s_Diag',siteName)).Text              = sprintf('%8d    ',dataOut.Diag);
                        if isempty(dataOut.flowDrive) && dataOut.Diag == 255
                            % test if IRGA = 7500 (no flowDrive) and Diag = 255
                            % and if it is set the warning light on
                            app.(sprintf('%s_Diag',siteName)).FontColor = [1 0 0];
                            ledColor = [1 1 0];                           
                        end
                    app.(sprintf('%s_FlowLabel',siteName)).Text         = sprintf('%6.2f    ',dataOut.flowRate);
                        if dataOut.flowRate < 13 
                            app.(sprintf('%s_FlowLabel',siteName)).FontColor = [1 0 0];
                            ledColor = [1 1 0];  
                        end
                    app.(sprintf('%s_FlowDriveLabel',siteName)).Text    = sprintf('%6.2f    ',dataOut.flowDrive);
                        if dataOut.flowDrive > 90 
                            app.(sprintf('%s_FlowDriveLabel',siteName)).FontColor = [1 0 0];
                            ledColor = [1 1 0];  
                        end
                    app.(sprintf('%s_TinLabel',siteName)).Text          = sprintf('%6.2f    ',dataOut.tempIn);
                    app.(sprintf('%s_ToutLabel',siteName)).Text         = sprintf('%6.2f    ',dataOut.tempOut);
                        if ~isempty(dataOut.tempOut) && abs(dataOut.tempOut-dataOut.tempIn) > 3
                            app.(sprintf('%s_TinLabel',siteName)).FontColor = [1 0 0];
                            app.(sprintf('%s_ToutLabel',siteName)).FontColor = [1 0 0];
                            ledColor = [1 1 0];
                        end
                    app.(sprintf('%s_PheadLabel',siteName)).Text        = sprintf('%6.2f    ',dataOut.Phead);
                    app.(sprintf('%s_PairLabel',siteName)).Text         = sprintf('%6.2f    ',dataOut.Pair);
                    app.(sprintf('%s_CO2Label',siteName)).Text          = sprintf('%6.2f    ',dataOut.CO2);
                    app.(sprintf('%s_H2OLabel',siteName)).Text          = sprintf('%6.2f    ',dataOut.H2O);
                    app.(sprintf('%s_Diag7700Label',siteName)).Text     = sprintf('%6.2f    ',dataOut.diag7700);
                    app.(sprintf('%s_SS7700Label',siteName)).Text       = sprintf('%6.2f    ',dataOut.signalStrength7700);
                         if dataOut.signalStrength7700 < 20
                            app.(sprintf('%s_SS7700Label',siteName)).FontColor = [1 0 0];
                            ledColor = [1 1 0];
                         end                   
                    app.(sprintf('%s_CH4Label',siteName)).Text = sprintf('%6.2f    ',dataOut.CH4);                    
                    app.(sprintf('%s_Lamp',siteName)).Color = ledColor;
                    app.(sprintf('%s_LastError',siteName)).Text = '';       % clear last error
                    results = 0;              
                else
                    app.(sprintf('%s_Lamp',siteName)).Color = [1 0 0];
                    app.(sprintf('%s_LastError',siteName)).Text = [cError allData];                    
                end              

            catch 
                app.(sprintf('%s_Lamp',siteName)).Color = [1 0 0];
                fprintf('*ERROR for %s site.\n',siteName);
                %disp(dataOut)
            end
            drawnow
        end
        
        function results = clean_site_data(app,siteName)
            try
                app.(sprintf('%s_Lamp',siteName)).Color = [0.8 0.8 0.8];
                app.(sprintf('%s_Diag',siteName)).Text = '';
                app.(sprintf('%s_FlowLabel',siteName)).Text = '';
                app.(sprintf('%s_FlowDriveLabel',siteName)).Text = '';
                app.(sprintf('%s_TinLabel',siteName)).Text = '';
                app.(sprintf('%s_ToutLabel',siteName)).Text = '';
                app.(sprintf('%s_PheadLabel',siteName)).Text = '';
                app.(sprintf('%s_PairLabel',siteName)).Text = '';
                app.(sprintf('%s_CO2Label',siteName)).Text = '';
                app.(sprintf('%s_H2OLabel',siteName)).Text = '';
                app.(sprintf('%s_Diag7700Label',siteName)).Text = '';
                app.(sprintf('%s_SS7700Label',siteName)).Text = '';
                app.(sprintf('%s_CH4Label',siteName)).Text = '';   
                                        
                % return font colors to black
                app.(sprintf('%s_Diag',siteName)).FontColor = [0 0 0];
                app.(sprintf('%s_FlowLabel',siteName)).FontColor = [0 0 0];
                app.(sprintf('%s_FlowDriveLabel',siteName)).FontColor = [0 0 0];
                app.(sprintf('%s_TinLabel',siteName)).FontColor = [0 0 0];
                app.(sprintf('%s_ToutLabel',siteName)).FontColor = [0 0 0];
                app.(sprintf('%s_SS7700Label',siteName)).FontColor = [0 0 0];
                results = 0;    
            catch
                results = 1;
            end
            drawnow;
        end
        
        function mainTimerCallback(app, ~, event)
            UpdateAllButtonPushed(app,event)
        end
        
        % ==================================================
        % halfHourEvent
        %
        % Calculates stats, stores HF and HH data
        %===================================================
        function halfHourEvent(app,currentHHour)
           
            % Save current data into dataHF
            dataHF = app.siteData;
            
            % save high frequency data using currentHHour (need an
            % end-of-period time stamp)
            fileFolder = fullfile(app.outputPathHF,datestr(currentHHour,'yyyymmdd'));
            if ~exist(fileFolder,"dir")
                mkdir(fileFolder)                     
            end
            fileName = sprintf(app.outputFileNameHF,datestr(currentHHour,'yyyymmddHHMM'));
            filePathHF = fullfile(fileFolder,fileName);
            save(filePathHF,'dataHF')
            
            % clean data buffer
            app.siteData = [];
            app.sampleNum = 0;            
            
            try
                %% calculate hhourly stats            
                % cycle through all sites
                strAllSiteNames = fieldnames(dataHF);             
                for cntSiteName = 1:length(strAllSiteNames)
                    strSiteName = char(strAllSiteNames{cntSiteName});
                    % pick only site names, skip TimeVector
                    if ~strcmpi(strSiteName,"TimeVector")     
                        % now get all variables that need averaging
                        strAllVariables = fieldnames(dataHF(1).(strSiteName));
                        for cntVarName = 1:length(strAllVariables)
                            strVarName = char(strAllVariables{cntVarName});
                            dataIn = get_stats_field_fast(dataHF,[strSiteName '.' strVarName]);                        
                            if ~strcmpi(strVarName,"TimeVector")
                                tmpStats.(strSiteName).(strVarName).avg = mean(dataIn(~isnan(dataIn)));
                                tmpStats.(strSiteName).(strVarName).max = max(dataIn(~isnan(dataIn)));
                                tmpStats.(strSiteName).(strVarName).min = min(dataIn(~isnan(dataIn)));
                                tmpStats.(strSiteName).(strVarName).std = std(dataIn(~isnan(dataIn)));
                            else
                                % Store the last time stamp collected from the instrument
                                % as datenum, but store the time only (no date)
                                tmpStats.(strSiteName).Time = dataIn(end)-floor(dataIn(end));
                                % Store also the TimeVector 
                                tmpStats.(strSiteName).TimeVector = currentHHour;
                                % Calculate stats on the sampling rate (how often is data collected)
                                tmpSampleRate = diff(dataIn *24*3600);
                                if ~isempty(tmpSampleRate)
                                    tmpStats.(strSiteName).SampleRate.avg = mean(tmpSampleRate); %#ok<*AGROW> 
                                    tmpStats.(strSiteName).SampleRate.max = max(tmpSampleRate);
                                    tmpStats.(strSiteName).SampleRate.min = min(tmpSampleRate);
                                    tmpStats.(strSiteName).SampleRate.std = std(tmpSampleRate);
                                else
                                    tmpStats.(strSiteName).SampleRate.avg = NaN; 
                                    tmpStats.(strSiteName).SampleRate.max = NaN;
                                    tmpStats.(strSiteName).SampleRate.min = NaN;
                                    tmpStats.(strSiteName).SampleRate.std = NaN;      
                                end
                            end
                        end
                        tmpStats.(strSiteName).numOfSamples = length(dataIn);
                    end
                end
                % Add TimeVector to the main structure
                tmpStats.TimeVector = currentHHour;
                % save half hourly stats using currentHHour (need an
                % end-of-period time stamp). 
                fileName = sprintf(app.outputFileNameHH,datestr(currentHHour,'yyyymmdd'));
                filePathHH = fullfile(app.outputPathHH,fileName);
                % check if the file with the same name already exists there
                if exist(filePathHH,'file')
                    load(filePathHH,'Stats');
                    numRec = length(Stats);
                else
                    numRec = 0;
                end
                numRec = numRec+1;
                % append the new record to the existing ones
                Stats(numRec) = tmpStats;
                % save hhour stats
                save(filePathHH,'Stats');
                % also save the data into database (only the last record
                Stats = tmpStats;
                allSites = {'BB1','BB2','DSM','RBM','HOGG','YOUNG','OHM'};
                for cntSite = 1:length(allSites)
                    currentSite = allSites{cntSite};
                    excludeSites = setdiff(allSites,currentSite);
                    if strcmpi(currentSite,'BB1')
                        siteName = 'BB';
                    else
                        siteName = currentSite;
                    end
                    % append the year to outputPathDB
                    dbasePath = fullfile(app.outputPathDB,datestr(currentHHour,'yyyy'));
                    [~,~,~, ~] = ...
                        db_new_eddy(Stats,[],...
                                    fullfile(dbasePath,siteName,'monitorSites'),...
                                    0,excludeSites,[],NaN); 
                end
            catch ME
                % If an error, skip hhour calculations
                % Display the error message for troubleshooting purposes.
                disp(ME.message);
            end       
        end % function halfHourEvent
    end % methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Check which PC is app running on (ENG0x or vinimet) and

            if ~exist('p:\database','dir')
                app.outputPathHF = 'd:/junk/monitorSiteLI7x00/data';
                app.outputPathHH = 'd:/junk/monitorSiteLI7x00/hhour';
                app.outputPathDB = 'd:/junk/monitorSiteLI7x00/database';
                app.delayCalling = 20;      % offset calling sites by 20 seconds to avoid overlap with vinimet
                app.samplingPeriodSec = 60; % faster sampling during app design
            else
                app.outputPathHF = 'P:/Sites/Common/monitorSiteLI7x00/data';
                app.outputPathHH = 'P:/Sites/Common/monitorSiteLI7x00/hhour';
                app.outputPathDB = 'P:/Database';
                app.delayCalling = 0;
                app.samplingPeriodSec = 180;
            end
            app.Label_15.Text = app.outputPathDB; 
            
            % updated data once to populate the table
            UpdateAllButtonPushed(app)
            
            % stop the timer function if already running
            t = timerfind('Name','siteUpdateTimer');
            if ~isempty(t)
                stop(t)
                delete(t)
            end
            
            % calculate delay time so the sampling time can be aligned to
            % sample at 0 seconds into samplingPeriodSec
            tv = now;
            samplesPerDay = 24*3600/app.samplingPeriodSec;         
            nextPeriod = ceil(tv*samplesPerDay)/samplesPerDay;
            delayTime = round((nextPeriod-tv)*24*3600) + app.delayCalling;  % delay time in seconds
            % create a timer that will update data every
            % 5 minutes (300s). 
            % to speedup debugging, run the timer faster (once every 60 seconds)
            app.timerMain = timer( "timerfcn",@app.mainTimerCallback,...
                                   "StartDelay",delayTime,...
                                   "Period",app.samplingPeriodSec,...
                                   "ExecutionMode",'fixedRate',...
                                   "Name",'siteUpdateTimer');
            % then start the timerMain
            start(app.timerMain)
        end

        % Button pushed function: UpdateAllButton
        function UpdateAllButtonPushed(app, event)

            % Check if this is a new 30-min period and,
            % if it is, store the collected data (if there is any)
            if app.sampleNum > 0
                % check if this is a new 30min period
                lastHHour = fix(app.siteData(app.sampleNum).TimeVector*48)/48;
                currentHHour = fix(now*48)/48;
                if lastHHour < currentHHour
                    % calculate hhourly stats
                    % calculate stats for all variables for each site
                    % store high frequency and half-hourly values
                    halfHourEvent(app,currentHHour)
                end
                
            end
                
            % There is a new data sample being collected. Increment counter
            app.sampleNum = app.sampleNum + 1;
            app.siteData(app.sampleNum).TimeVector = now;
            app.LastUpdateLabel.Text = datestr(now,31);
                        
            siteAddress = "173.181.138.49";
            siteName = 'BB1';
            downloadData(app,siteName,siteAddress);
        
            siteName = 'BB2';
            siteAddress = "96.1.106.219";            
            downloadData(app,siteName,siteAddress);
            
            siteName = 'DSM';
            siteAddress = "173.181.139.5";            
            downloadData(app,siteName,siteAddress);
                        
            siteName = 'RBM';
            siteAddress = "173.181.139.4";            
            downloadData(app,siteName,siteAddress);

            siteName = 'HOGG';
            siteAddress = "68.182.37.103";            
            downloadData(app,siteName,siteAddress);

            siteName = 'YOUNG';
            siteAddress = "68.182.37.116";            
            downloadData(app,siteName,siteAddress);
 
            siteName = 'OHM';
            siteAddress = "68.179.19.209";            
            downloadData(app,siteName,siteAddress,7200);
            
        end

        % Close request function: MicrometSiteInstrumentsUIFigure
        function MicrometSiteInstrumentsUIFigureCloseRequest(app, event)
            stop(app.timerMain)
            delete(app.timerMain)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MicrometSiteInstrumentsUIFigure = uifigure('Visible', 'off');
            app.MicrometSiteInstrumentsUIFigure.Name = 'Micromet Site Instruments';
            app.MicrometSiteInstrumentsUIFigure.CloseRequestFcn = createCallbackFcn(app, @MicrometSiteInstrumentsUIFigureCloseRequest, true);
            app.Panel = uipanel(app.MicrometSiteInstrumentsUIFigure);
            app.Panel.Title = ' ';
            app.GridLayout = uigridlayout(app.Panel);
            app.BB1_Lamp = uilamp(app.GridLayout);
            app.BB1Label = uilabel(app.GridLayout);
            app.BB1Label.Text = 'BB1';
            app.Label_17 = uilabel(app.GridLayout);
            app.Label_17.Text = '';
            app.DiagLabel = uilabel(app.GridLayout);
            app.DiagLabel.Text = '';
            app.slpmLabel = uilabel(app.GridLayout);
            app.slpmLabel.Text = 'slpm';
            app.Label_16 = uilabel(app.GridLayout);
            app.Label_16.Text = '%';
            app.BB2Label = uilabel(app.GridLayout);
            app.BB2Label.Text = 'BB2';
            app.degCLabel = uilabel(app.GridLayout);
            app.degCLabel.Text = 'deg C';
            app.degCLabel_2 = uilabel(app.GridLayout);
            app.degCLabel_2.Text = 'deg C';
            app.DSMLabel = uilabel(app.GridLayout);
            app.DSMLabel.Text = 'DSM';
            app.RBMLabel = uilabel(app.GridLayout);
            app.RBMLabel.Text = 'RBM';
            app.HOGGLabel = uilabel(app.GridLayout);
            app.HOGGLabel.Text = 'HOGG';
            app.YOUNGLabel = uilabel(app.GridLayout);
            app.YOUNGLabel.Text = 'YOUNG';
            app.BB2_Lamp = uilamp(app.GridLayout);
            app.DSM_Lamp = uilamp(app.GridLayout);
            app.RBM_Lamp = uilamp(app.GridLayout);
            app.HOGG_Lamp = uilamp(app.GridLayout);
            app.YOUNG_Lamp = uilamp(app.GridLayout);
            app.umolmolLabel = uilabel(app.GridLayout);
            app.umolmolLabel.Text = 'umol/mol';
            app.mmolmolLabel = uilabel(app.GridLayout);
            app.mmolmolLabel.Text = 'mmol/mol';
            app.kPaLabel = uilabel(app.GridLayout);
            app.kPaLabel.Text = 'kPa';
            app.kPaLabel_2 = uilabel(app.GridLayout);
            app.kPaLabel_2.Text = 'kPa';
            app.Label_18 = uilabel(app.GridLayout);
            app.Label_18.Text = {''; ''};
            app.Label_19 = uilabel(app.GridLayout);
            app.Label_19.Text = {''; ''};
            app.umolmolLabel_2 = uilabel(app.GridLayout);
            app.umolmolLabel_2.Text = {'umol/mol'; ''};
            app.BB1_Diag = uilabel(app.GridLayout);
            app.BB1_Diag.Text = '';
            app.BB1_FlowLabel = uilabel(app.GridLayout);
            app.BB1_FlowLabel.Text = '';
            app.BB1_FlowDriveLabel = uilabel(app.GridLayout);
            app.BB1_FlowDriveLabel.Text = '';
            app.BB1_TinLabel = uilabel(app.GridLayout);
            app.BB1_TinLabel.Text = '';
            app.BB1_ToutLabel = uilabel(app.GridLayout);
            app.BB1_ToutLabel.Text = '';
            app.BB1_PheadLabel = uilabel(app.GridLayout);
            app.BB1_PheadLabel.Text = '';
            app.BB1_PairLabel = uilabel(app.GridLayout);
            app.BB1_PairLabel.Text = '';
            app.BB1_CO2Label = uilabel(app.GridLayout);
            app.BB1_CO2Label.Text = '';
            app.BB1_H2OLabel = uilabel(app.GridLayout);
            app.BB1_H2OLabel.Text = '';
            app.BB1_Diag7700Label = uilabel(app.GridLayout);
            app.BB1_Diag7700Label.Text = '';
            app.BB1_SS7700Label = uilabel(app.GridLayout);
            app.BB1_SS7700Label.Text = '';
            app.BB1_CH4Label = uilabel(app.GridLayout);
            app.BB1_CH4Label.Text = '';
            app.BB2_Diag = uilabel(app.GridLayout);
            app.BB2_Diag.Text = '';
            app.BB2_FlowLabel = uilabel(app.GridLayout);
            app.BB2_FlowLabel.Text = '';
            app.BB2_FlowDriveLabel = uilabel(app.GridLayout);
            app.BB2_FlowDriveLabel.Text = '';
            app.BB2_TinLabel = uilabel(app.GridLayout);
            app.BB2_TinLabel.Text = '';
            app.BB2_ToutLabel = uilabel(app.GridLayout);
            app.BB2_ToutLabel.Text = '';
            app.BB2_PheadLabel = uilabel(app.GridLayout);
            app.BB2_PheadLabel.Text = '';
            app.BB2_PairLabel = uilabel(app.GridLayout);
            app.BB2_PairLabel.Text = '';
            app.BB2_CO2Label = uilabel(app.GridLayout);
            app.BB2_CO2Label.Text = '';
            app.BB2_H2OLabel = uilabel(app.GridLayout);
            app.BB2_H2OLabel.Text = '';
            app.BB2_Diag7700Label = uilabel(app.GridLayout);
            app.BB2_Diag7700Label.Text = '';
            app.BB2_SS7700Label = uilabel(app.GridLayout);
            app.BB2_SS7700Label.Text = '';
            app.BB2_CH4Label = uilabel(app.GridLayout);
            app.BB2_CH4Label.Text = '';
            app.DSM_Diag = uilabel(app.GridLayout);
            app.DSM_Diag.Text = '';
            app.DSM_CH4Label = uilabel(app.GridLayout);
            app.DSM_CH4Label.Text = '';
            app.DSM_FlowLabel = uilabel(app.GridLayout);
            app.DSM_FlowLabel.Text = '';
            app.DSM_FlowDriveLabel = uilabel(app.GridLayout);
            app.DSM_FlowDriveLabel.Text = '';
            app.DSM_TinLabel = uilabel(app.GridLayout);
            app.DSM_TinLabel.Text = '';
            app.DSM_ToutLabel = uilabel(app.GridLayout);
            app.DSM_ToutLabel.Text = '';
            app.DSM_PheadLabel = uilabel(app.GridLayout);
            app.DSM_PheadLabel.Text = '';
            app.DSM_PairLabel = uilabel(app.GridLayout);
            app.DSM_PairLabel.Text = '';
            app.DSM_CO2Label = uilabel(app.GridLayout);
            app.DSM_CO2Label.Text = '';
            app.DSM_H2OLabel = uilabel(app.GridLayout);
            app.DSM_H2OLabel.Text = '';
            app.DSM_Diag7700Label = uilabel(app.GridLayout);
            app.DSM_Diag7700Label.Text = '';
            app.DSM_SS7700Label = uilabel(app.GridLayout);
            app.DSM_SS7700Label.Text = '';
            app.RBM_Diag = uilabel(app.GridLayout);
            app.RBM_Diag.Text = '';
            app.RBM_CH4Label = uilabel(app.GridLayout);
            app.RBM_CH4Label.Text = '';
            app.RBM_FlowLabel = uilabel(app.GridLayout);
            app.RBM_FlowLabel.Text = '';
            app.RBM_FlowDriveLabel = uilabel(app.GridLayout);
            app.RBM_FlowDriveLabel.Text = '';
            app.RBM_TinLabel = uilabel(app.GridLayout);
            app.RBM_TinLabel.Text = '';
            app.RBM_ToutLabel = uilabel(app.GridLayout);
            app.RBM_ToutLabel.Text = '';
            app.RBM_PheadLabel = uilabel(app.GridLayout);
            app.RBM_PheadLabel.Text = '';
            app.RBM_PairLabel = uilabel(app.GridLayout);
            app.RBM_PairLabel.Text = '';
            app.RBM_CO2Label = uilabel(app.GridLayout);
            app.RBM_CO2Label.Text = '';
            app.RBM_H2OLabel = uilabel(app.GridLayout);
            app.RBM_H2OLabel.Text = '';
            app.RBM_Diag7700Label = uilabel(app.GridLayout);
            app.RBM_Diag7700Label.Text = '';
            app.RBM_SS7700Label = uilabel(app.GridLayout);
            app.RBM_SS7700Label.Text = '';
            app.HOGG_Diag = uilabel(app.GridLayout);
            app.HOGG_Diag.Text = '';
            app.HOGG_CH4Label = uilabel(app.GridLayout);
            app.HOGG_CH4Label.Text = '';
            app.HOGG_FlowLabel = uilabel(app.GridLayout);
            app.HOGG_FlowLabel.Text = '';
            app.HOGG_FlowDriveLabel = uilabel(app.GridLayout);
            app.HOGG_FlowDriveLabel.Text = '';
            app.HOGG_TinLabel = uilabel(app.GridLayout);
            app.HOGG_TinLabel.Text = '';
            app.HOGG_ToutLabel = uilabel(app.GridLayout);
            app.HOGG_ToutLabel.Text = '';
            app.HOGG_PheadLabel = uilabel(app.GridLayout);
            app.HOGG_PheadLabel.Text = '';
            app.HOGG_PairLabel = uilabel(app.GridLayout);
            app.HOGG_PairLabel.Text = '';
            app.HOGG_CO2Label = uilabel(app.GridLayout);
            app.HOGG_CO2Label.Text = '';
            app.HOGG_H2OLabel = uilabel(app.GridLayout);
            app.HOGG_H2OLabel.Text = '';
            app.HOGG_Diag7700Label = uilabel(app.GridLayout);
            app.HOGG_Diag7700Label.Text = '';
            app.HOGG_SS7700Label = uilabel(app.GridLayout);
            app.HOGG_SS7700Label.Text = '';
            app.YOUNG_Diag = uilabel(app.GridLayout);
            app.YOUNG_Diag.Text = '';
            app.YOUNG_SS7700Label = uilabel(app.GridLayout);
            app.YOUNG_SS7700Label.Text = '';
            app.YOUNG_Diag7700Label = uilabel(app.GridLayout);
            app.YOUNG_Diag7700Label.Text = '';
            app.YOUNG_H2OLabel = uilabel(app.GridLayout);
            app.YOUNG_H2OLabel.Text = '';
            app.YOUNG_CO2Label = uilabel(app.GridLayout);
            app.YOUNG_CO2Label.Text = '';
            app.YOUNG_PairLabel = uilabel(app.GridLayout);
            app.YOUNG_PairLabel.Text = '';
            app.YOUNG_PheadLabel = uilabel(app.GridLayout);
            app.YOUNG_PheadLabel.Text = '';
            app.YOUNG_ToutLabel = uilabel(app.GridLayout);
            app.YOUNG_ToutLabel.Text = '';
            app.YOUNG_TinLabel = uilabel(app.GridLayout);
            app.YOUNG_TinLabel.Text = '';
            app.YOUNG_FlowDriveLabel = uilabel(app.GridLayout);
            app.YOUNG_FlowDriveLabel.Text = '';
            app.YOUNG_FlowLabel = uilabel(app.GridLayout);
            app.YOUNG_FlowLabel.Text = '';
            app.YOUNG_CH4Label = uilabel(app.GridLayout);
            app.YOUNG_CH4Label.Text = '';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '';
            app.DiagIRGALabel = uilabel(app.GridLayout);
            app.DiagIRGALabel.Text = 'DiagIRGA';
            app.FlowLabel = uilabel(app.GridLayout);
            app.FlowLabel.Text = 'Flow ';
            app.FlowDriveLabel = uilabel(app.GridLayout);
            app.FlowDriveLabel.Text = 'FlowDrive';
            app.TinLabel = uilabel(app.GridLayout);
            app.TinLabel.Text = 'Tin';
            app.ToutLabel = uilabel(app.GridLayout);
            app.ToutLabel.Text = 'Tout';
            app.PheadLabel = uilabel(app.GridLayout);
            app.PheadLabel.Text = 'Phead';
            app.PairLabel = uilabel(app.GridLayout);
            app.PairLabel.Text = 'Pair';
            app.CO2_dryLabel = uilabel(app.GridLayout);
            app.CO2_dryLabel.Text = 'CO2_dry';
            app.H2OLabel = uilabel(app.GridLayout);
            app.H2OLabel.Text = 'H2O';
            app.Diag7700Label = uilabel(app.GridLayout);
            app.Diag7700Label.Text = 'Diag7700';
            app.Signal7700Label = uilabel(app.GridLayout);
            app.Signal7700Label.Text = 'Signal 7700';
            app.CH4Label = uilabel(app.GridLayout);
            app.CH4Label.Text = 'CH4';
            app.Label_15 = uilabel(app.GridLayout);
            app.Label_15.Text = '';
            app.BB1_LastError = uilabel(app.GridLayout);
            app.BB1_LastError.Text = '';
            app.BB2_LastError = uilabel(app.GridLayout);
            app.BB2_LastError.Text = '';
            app.Label_20 = uilabel(app.GridLayout);
            app.Label_20.Text = '';
            app.DSM_LastError = uilabel(app.GridLayout);
            app.DSM_LastError.Text = '';
            app.RBM_LastError = uilabel(app.GridLayout);
            app.RBM_LastError.Text = '';
            app.HOGG_LastError = uilabel(app.GridLayout);
            app.HOGG_LastError.Text = '';
            app.YOUNG_LastError = uilabel(app.GridLayout);
            app.YOUNG_LastError.Text = '';
            app.OHM_Lamp = uilamp(app.GridLayout);
            app.OHMLabel = uilabel(app.GridLayout);
            app.OHMLabel.Text = 'OHM';
            app.OHM_Diag = uilabel(app.GridLayout);
            app.OHM_Diag.Text = '';
            app.OHM_FlowLabel = uilabel(app.GridLayout);
            app.OHM_FlowLabel.Text = '';
            app.OHM_FlowDriveLabel = uilabel(app.GridLayout);
            app.OHM_FlowDriveLabel.Text = '';
            app.OHM_TinLabel = uilabel(app.GridLayout);
            app.OHM_TinLabel.Text = '';
            app.OHM_ToutLabel = uilabel(app.GridLayout);
            app.OHM_ToutLabel.Text = '';
            app.OHM_PheadLabel = uilabel(app.GridLayout);
            app.OHM_PheadLabel.Text = '';
            app.OHM_PairLabel = uilabel(app.GridLayout);
            app.OHM_PairLabel.Text = '';
            app.OHM_CO2Label = uilabel(app.GridLayout);
            app.OHM_CO2Label.Text = '';
            app.OHM_H2OLabel = uilabel(app.GridLayout);
            app.OHM_H2OLabel.Text = '';
            app.OHM_CH4Label = uilabel(app.GridLayout);
            app.OHM_CH4Label.Text = '';
            app.OHM_SS7700Label = uilabel(app.GridLayout);
            app.OHM_SS7700Label.Text = '';
            app.OHM_Diag7700Label = uilabel(app.GridLayout);
            app.OHM_Diag7700Label.Text = '';
            app.OHM_LastError = uilabel(app.GridLayout);
            app.OHM_LastError.Text = '';
            app.LastdataupdatePanel = uipanel(app.MicrometSiteInstrumentsUIFigure);
            app.LastdataupdatePanel.Title = 'Last data update:';
            app.LastUpdateLabel = uilabel(app.LastdataupdatePanel);
            app.LastUpdateLabel.Text = '';
            app.UpdateAllButton = uibutton(app.MicrometSiteInstrumentsUIFigure, 'push');
            app.UpdateAllButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateAllButtonPushed, true);
            app.UpdateAllButton.Enable = 'off';
            app.UpdateAllButton.Visible = 'off';
            app.UpdateAllButton.Text = 'Update All';
            app.LI7x00MonitoringAppLabel = uilabel(app.MicrometSiteInstrumentsUIFigure);
            app.LI7x00MonitoringAppLabel.Text = 'LI-7x00 Monitoring App';
            % Show the figure after all components are created
            app.MicrometSiteInstrumentsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMonitorSiteLI7x00

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MicrometSiteInstrumentsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MicrometSiteInstrumentsUIFigure)
        end
    end
end