classdef GUI_visualize_one_fit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Button                  matlab.ui.control.Button
        LoaddataButton          matlab.ui.control.Button
        DateDatePicker          matlab.ui.control.DatePicker
        DateDatePickerLabel     matlab.ui.control.Label
        DatapathEditField       matlab.ui.control.EditField
        DatapathEditFieldLabel  matlab.ui.control.Label
        BrowseButton            matlab.ui.control.Button
        Fit2DropDown            matlab.ui.control.DropDown
        Fit2DropDownLabel       matlab.ui.control.Label
        Fit1DropDown            matlab.ui.control.DropDown
        Fit1DropDownLabel       matlab.ui.control.Label
        GasDropDown             matlab.ui.control.DropDown
        GasDropDownLabel        matlab.ui.control.Label
        HourSpinner             matlab.ui.control.Spinner
        HourSpinnerLabel        matlab.ui.control.Label
        ChamberSpinner          matlab.ui.control.Spinner
        ChamberSpinnerLabel     matlab.ui.control.Label
        FileEditField           matlab.ui.control.EditField
        FileEditFieldLabel      matlab.ui.control.Label
        RunButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        dataStruct % Description
        oldFileName
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setup_UdeM_calc;            
            [~,app.DatapathEditField.Value ,~,~] = fr_get_local_path;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % First check if the data set that corresponds 
            % to the data and path is loaded already by comparing
            % the file name of the last loaded file to the current
            % file name
            
            % current file name
            fileName = fullfile(app.DatapathEditField.Value,...
                    [datestr(app.DateDatePicker.Value,'yyyymmdd') ...
                     '_recalcs_UdeM.mat' ]);
            if ~strcmpi(fileName,app.oldFileName)
                % if not the same as the file name of the last loaded
                % data set, then load the data first before plotting
                LoaddataButtonPushed(app, event)
            end
            % if correct data is loaded, plot the fits
            figure(101);clf;
            UdeM_visualize_one_fit(app.dataStruct,...
                                   app.ChamberSpinner.Value,...
                                   app.HourSpinner.Value,...
                                   app.Fit1DropDown.Value,...
                                   app.GasDropDown.Value,...
                                   101,...
                                   [],...
                                   app.Fit2DropDown.Value)
        end

        % Value changed function: FileEditField
        function FileEditFieldValueChanged(app, event)
            value = app.FileEditField.Value;
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
             app.DatapathEditField.Value = uigetdir;
        end

        % Value changed function: DatapathEditField
        function DatapathEditFieldValueChanged(app, event)


        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            fileName = fullfile(app.DatapathEditField.Value,...
                                [datestr(app.DateDatePicker.Value,'yyyymmdd') ...
                                 '_recalcs_UdeM.mat' ]);
            if ~strcmpi(fileName,app.oldFileName)
                app.LoaddataButton.Enable = false;
                 f=uifigure;
                 try
                    d = uiprogressdlg(f,'Title','Please Wait',...
                                     'Message','Loading data file...');
                    app.FileEditField.Value = fileName;
                    app.RunButton.Enable = false; 
                    drawnow
                    temp = load(fileName,'dataStruct');
                    app.dataStruct  = temp.dataStruct;
                    app.RunButton.Enable = true;
                    drawnow
                    app.oldFileName = fileName;
                    close(d);
                    close(f);
                 catch
                     app.oldFileName = '';
                     close(f)
                 end
                 app.LoaddataButton.Enable = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.FileEditFieldLabel = uilabel(app.UIFigure);
            app.FileEditFieldLabel.Visible = 'off';
            app.FileEditFieldLabel.Text = 'File ';
            app.FileEditField = uieditfield(app.UIFigure, 'text');
            app.FileEditField.ValueChangedFcn = createCallbackFcn(app, @FileEditFieldValueChanged, true);
            app.FileEditField.Visible = 'off';
            app.ChamberSpinnerLabel = uilabel(app.UIFigure);
            app.ChamberSpinnerLabel.Text = 'Chamber #';
            app.ChamberSpinner = uispinner(app.UIFigure);
            app.ChamberSpinner.Limits = [1 18];
            app.ChamberSpinner.Value = 1;
            app.HourSpinnerLabel = uilabel(app.UIFigure);
            app.HourSpinnerLabel.Text = 'Hour:';
            app.HourSpinner = uispinner(app.UIFigure);
            app.HourSpinner.Limits = [1 24];
            app.HourSpinner.Value = 1;
            app.GasDropDownLabel = uilabel(app.UIFigure);
            app.GasDropDownLabel.Text = 'Gas:';
            app.GasDropDown = uidropdown(app.UIFigure);
            app.GasDropDown.Items = {'co2', 'ch4'};
            app.GasDropDown.Value = 'co2';
            app.Fit1DropDownLabel = uilabel(app.UIFigure);
            app.Fit1DropDownLabel.Text = 'Fit 1';
            app.Fit1DropDown = uidropdown(app.UIFigure);
            app.Fit1DropDown.Items = {'exp_B', 'exp_L', 'quad_B', 'lin_B'};
            app.Fit1DropDown.Value = 'exp_B';
            app.Fit2DropDownLabel = uilabel(app.UIFigure);
            app.Fit2DropDownLabel.Text = 'Fit 2';
            app.Fit2DropDown = uidropdown(app.UIFigure);
            app.Fit2DropDown.Items = {'exp_B', 'exp_L', 'quad_B', 'lin_B', 'lin_C'};
            app.Fit2DropDown.Value = 'lin_C';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = '...';
            app.DatapathEditFieldLabel = uilabel(app.UIFigure);
            app.DatapathEditFieldLabel.Text = 'Data path:';
            app.DatapathEditField = uieditfield(app.UIFigure, 'text');
            app.DatapathEditField.ValueChangedFcn = createCallbackFcn(app, @DatapathEditFieldValueChanged, true);
            app.DateDatePickerLabel = uilabel(app.UIFigure);
            app.DateDatePickerLabel.Text = 'Date:';
            app.DateDatePicker = uidatepicker(app.UIFigure);
            app.DateDatePicker.Value = datetime([2019 8 1]);
            app.LoaddataButton = uibutton(app.UIFigure, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_visualize_one_fit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end