classdef plotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        HelpPushTool                   matlab.ui.container.toolbar.PushTool
        RightPanel                     matlab.ui.container.Panel
        Subpanel                       matlab.ui.container.Panel
        TraceanalysisDropDown          matlab.ui.control.DropDown
        TraceanalysisLabel             matlab.ui.control.Label
        CompareStages                  matlab.ui.control.Button
        CompareTraces                  matlab.ui.control.Button
        SummarizeButton                matlab.ui.control.Button
        TraceSpinner                   matlab.ui.control.Spinner
        TraceSpinnerLabel              matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        LeftUpperPanel                 matlab.ui.container.Panel
        GetDirButton_1                 matlab.ui.control.Button
        FolderEditField                matlab.ui.control.EditField
        YearDropDown                   matlab.ui.control.DropDown
        SiteDropDown                   matlab.ui.control.DropDown
        DatatypeDropDown               matlab.ui.control.DropDown
        DatatypeDropDownLabel          matlab.ui.control.Label
        FolderEditFieldLabel           matlab.ui.control.Label
        LevelButtonGroup_top           matlab.ui.container.ButtonGroup
        Stage2Button_top               matlab.ui.control.RadioButton
        Stage1Button_top               matlab.ui.control.RadioButton
        RawButton_top                  matlab.ui.control.RadioButton
        TracestoppanelListBox          matlab.ui.control.ListBox
        TracestoppanelListBoxLabel     matlab.ui.control.Label
        YearDropDownLabel              matlab.ui.control.Label
        SiteDropDownLabel              matlab.ui.control.Label
        LeftLowerPanel                 matlab.ui.container.Panel
        MatchSwitch                    matlab.ui.control.Switch
        MatchSwitchLabel               matlab.ui.control.Label
        GetDirButton_2                 matlab.ui.control.Button
        FolderEditField_2              matlab.ui.control.EditField
        YearDropDown_2                 matlab.ui.control.DropDown
        SiteDropDown_2                 matlab.ui.control.DropDown
        DatatypeDropDown_2             matlab.ui.control.DropDown
        LevelButtonGroup_btm           matlab.ui.container.ButtonGroup
        Stage2Button_btm               matlab.ui.control.RadioButton
        Stage1Button_btm               matlab.ui.control.RadioButton
        RawButton_btm                  matlab.ui.control.RadioButton
        DatatypeDropDown_2Label        matlab.ui.control.Label
        SiteDropDown_2Label            matlab.ui.control.Label
        YearDropDown_2Label            matlab.ui.control.Label
        FolderEditField_2Label         matlab.ui.control.Label
        TracesbottompanelListBox       matlab.ui.control.ListBox
        TracesbottompanelListBoxLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        uiFont_off = uistyle("FontColor",'#808080') % Greyed out text
        uiFont_on = uistyle("FontColor",'#000000') % Regular black text
        dBase % Used to streamline use of ta_plot_parents.m
    end
    
    methods (Access = private)
        
        % Get rid of plot data if it exists
        function kill_trace(app,panel)
            if panel==1
                ph = app.UIAxes.Children;
                ah = app.UIAxes;
            elseif panel==2
                ph = app.UIAxes2.Children;
                ah = app.UIAxes2;
            end

            if ~isempty(ph)
                ydata = zeros(size(ph.YData));
                ph.YData = ydata;
                ylabel(ah,'')
            end
        end
        
        function [tv, input1, input2] = getTrace_inputs(app)
            input1 = [];
            input2 = [];
            tv = [];
            selectedButton_top = app.LevelButtonGroup_top.SelectedObject;
            selectedButton_btm = app.LevelButtonGroup_btm.SelectedObject;

            % Folder for input #1
            if strcmp(selectedButton_top.Text,'Raw')
                if strcmp(app.SiteDropDown.Value,'ECCC')
                        datalevelFolder_1 = fullfile(app.FolderEditField.Value,...
                            app.YearDropDown.Value, app.SiteDropDown.Value,...
                            app.DatatypeDropDown.Value,'30min');
                else
                    datalevelFolder_1 = fullfile(app.FolderEditField.Value,...
                        app.YearDropDown.Value, app.SiteDropDown.Value,...
                        app.DatatypeDropDown.Value);
                end
            elseif strcmp(selectedButton_top.Text,'Stage1')
                datalevelFolder_1 = fullfile(app.FolderEditField.Value,...
                    app.YearDropDown.Value, app.SiteDropDown.Value,...
                    app.DatatypeDropDown.Value,'Clean');
            elseif strcmp(selectedButton_top.Text,'Stage2')
                datalevelFolder_1 = fullfile(app.FolderEditField.Value,...
                    app.YearDropDown.Value, app.SiteDropDown.Value,...
                    'Clean','SecondStage');
            elseif strcmp(selectedButton_top.Text,'Stage3')
                datalevelFolder_1 = fullfile(app.FolderEditField.Value,...
                    app.YearDropDown.Value, app.SiteDropDown.Value,...
                    'Clean','ThirdStage');
            elseif strcmp(selectedButton_top.Text,'Stage3+')
                datalevelFolder_1 = fullfile(app.FolderEditField.Value,...
                    app.YearDropDown.Value, app.SiteDropDown.Value,...
                    'Clean','ThirdStage_Default_Ustar');
            end

            % Folder for input #2
            if strcmp(selectedButton_btm.Text,'Raw')
                if strcmp(app.SiteDropDown_2.Value,'ECCC')
                    datalevelFolder_2 = fullfile(app.FolderEditField_2.Value,...
                        app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                        app.DatatypeDropDown_2.Value,'30min');
                else
                    datalevelFolder_2 = fullfile(app.FolderEditField_2.Value,...
                        app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                        app.DatatypeDropDown_2.Value);
                end
            elseif strcmp(selectedButton_btm.Text,'Stage1')
                datalevelFolder_2 = fullfile(app.FolderEditField_2.Value,...
                    app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                    app.DatatypeDropDown_2.Value,'Clean');
            elseif strcmp(selectedButton_btm.Text,'Stage2')
                datalevelFolder_2 = fullfile(app.FolderEditField_2.Value,...
                    app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                    'Clean','SecondStage');
            elseif strcmp(selectedButton_btm.Text,'Stage3')
                datalevelFolder_2 = fullfile(app.FolderEditField_2.Value,...
                    app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                    'Clean','ThirdStage');
            elseif strcmp(selectedButton_btm.Text,'Stage3+')
                datalevelFolder_2 = fullfile(app.FolderEditField_2.Value,...
                    app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                    'Clean','ThirdStage_Default_Ustar');
            end

            if isfolder(datalevelFolder_1)
                year1 = str2double(app.YearDropDown.Value);
                varname_1 = app.TracestoppanelListBox.Value;
                tv = read_bor(fullfile(datalevelFolder_1,'clean_tv'),8,[],year1);

                if isfile(fullfile(datalevelFolder_1,varname_1)) && ~isnan(year1)
                    input1 = read_bor(fullfile(datalevelFolder_1,varname_1),[],[],year1);
                    input1(input1==-9999) = NaN;
                end
            end

            if isfolder(datalevelFolder_2)
                year2 = str2double(app.YearDropDown_2.Value);
                varname_2 = app.TracesbottompanelListBox.Value;

                if isfile(fullfile(datalevelFolder_2,varname_2)) && ~isnan(year2)
                    input2 = read_bor(fullfile(datalevelFolder_2,varname_2),[],[],year2);
                    input2(input2==-9999) = NaN;
                end
            end
        end
        

        % Structure allows use of common code for top & bottom panel items
        function topBtm = get_topBtm_struct(app,value)
            
            if value==1 % Change to top panel item
                topBtm.value = 1;
                topBtm.FolderEditField = 'FolderEditField';
                topBtm.YearDropDown = 'YearDropDown';
                topBtm.SiteDropDown = 'SiteDropDown';
                topBtm.DatatypeDropDown = 'DatatypeDropDown';
                topBtm.LevelButtonGroup = 'LevelButtonGroup_top';
                topBtm.RawButton = 'RawButton_top';
                topBtm.Stage1Button = 'Stage1Button_top';
                topBtm.Stage2Button = 'Stage2Button_top';
                topBtm.TracesListBox = 'TracestoppanelListBox';
                topBtm.UIAxes_primary = 'UIAxes';
                topBtm.UIAxes_secondary = 'UIAxes2';

            elseif value==2 % Change top bottom panel item
                topBtm.value = 2;
                topBtm.FolderEditField = 'FolderEditField_2';
                topBtm.YearDropDown = 'YearDropDown_2';
                topBtm.SiteDropDown = 'SiteDropDown_2';
                topBtm.DatatypeDropDown = 'DatatypeDropDown_2';
                topBtm.LevelButtonGroup = 'LevelButtonGroup_btm';
                topBtm.RawButton = 'RawButton_btm';
                topBtm.Stage1Button = 'Stage1Button_btm';
                topBtm.Stage2Button = 'Stage2Button_btm';
                topBtm.TracesListBox = 'TracesbottompanelListBox';
                topBtm.UIAxes_primary = 'UIAxes2';
                topBtm.UIAxes_secondary = 'UIAxes';
            end
        end
        

        %--- Retrieve list of traces from folder for 'ListBox' ---%
        function Items = update_TracesItems(app,datalevelFolder)
            
            traceNames = dir(datalevelFolder);
            traceNames = traceNames(3:end);
            traceNames = struct2table(traceNames);
            idx1 = ~traceNames.isdir==1;
            idx2 = ~strcmp(traceNames.name,'clean_tv') & ~strcmp(traceNames.name,'TimeVector') & ~strcmp(traceNames.name,'.DS_Store');
            traceNames = traceNames.name(idx1 & idx2);
            n = length(traceNames);
            Items = cell(1,n+1);
            Items(1) = {'None'};
            if n==1
                Items(2) = {traceNames{1}};
            else
                Items(2:end) = traceNames';
            end
        end
        
        
        %--- Triggered when folder field is changed ---%
        function update_YearDropDown(app,topBtm)
            app.(topBtm.SiteDropDown).Items = {'None'};
            app.(topBtm.SiteDropDown).Value = app.(topBtm.SiteDropDown).Items(1);
            app.(topBtm.DatatypeDropDown).Items = {'None'};
            app.(topBtm.DatatypeDropDown).Value = app.(topBtm.DatatypeDropDown).Items(1);
            app.(topBtm.RawButton).Enable = "off";
            app.(topBtm.LevelButtonGroup).SelectedObject = app.(topBtm.RawButton);
            app.(topBtm.Stage1Button).Enable = "off";
            app.(topBtm.Stage2Button).Enable = "off";
            app.(topBtm.TracesListBox).Enable = 'off';
            app.(topBtm.TracesListBox).Items = {'None'};
            app.(topBtm.TracesListBox).Value = app.(topBtm.TracesListBox).Items(1);

            % Get rid of plot data if it exists
            kill_trace(app,topBtm.value)

            folderEditField_value = app.(topBtm.FolderEditField).Value;

            if isfolder(folderEditField_value)
                yearFolders = dir(folderEditField_value);
                yearFolders = yearFolders(3:end);
                yearFolders = struct2table(yearFolders);
                yearFolders = yearFolders(yearFolders.isdir==1,:);
                yearFolders = yearFolders.name;

                % Only retain folder names that could be years
                idx_valid_years = false(length(yearFolders),1);
                for i=1:length(yearFolders)
                    tmp = str2double(yearFolders{i});
                    if ~isempty(tmp)
                        if tmp>1900 && tmp<2199
                            idx_valid_years(i,1) = true;
                        end
                    end
                end
                yearFolders = yearFolders(idx_valid_years);
                % idx = ~strcmp(yearFolders,'Calculation_Procedures');
                % yearFolders = yearFolders(idx);

                if ~isempty(yearFolders)
                    Items = cell(1,length(yearFolders)+1);
                    Items(1) = {'Choose'};
                    Items(2:end) = yearFolders';
                    app.(topBtm.YearDropDown).Items = Items;
                    fid = fopen('lastFolderUsed.ini','w');
                    fprintf(fid,'%s',folderEditField_value);
                    fclose(fid);
                else
                    Items = {'None'};
                    app.(topBtm.YearDropDown).Items = Items;
                end
            else
                Items = {'None'};
                app.(topBtm.YearDropDown).Items = Items;
            end
            app.(topBtm.YearDropDown).Value = app.(topBtm.YearDropDown).Items(1);
        end
        
        
        function update_SiteDropDown(app,topBtm)
            
            app.(topBtm.DatatypeDropDown).Items = {'None'};
            app.(topBtm.DatatypeDropDown).Value = app.(topBtm.DatatypeDropDown).Items(1);
            app.(topBtm.LevelButtonGroup).SelectedObject = app.(topBtm.RawButton);
            app.(topBtm.RawButton).Enable = "off";
            app.(topBtm.Stage1Button).Enable = "off";
            app.(topBtm.Stage2Button).Enable = "off";
            app.(topBtm.TracesListBox).Enable = 'off';
            app.(topBtm.TracesListBox).Items = {'None'};
            app.(topBtm.TracesListBox).Value = app.(topBtm.TracesListBox).Items(1);

            % Get rid of plot data if it exists
            kill_trace(app,topBtm.value)

            YearDropDown_value = app.(topBtm.YearDropDown).Value;

            if strcmp(YearDropDown_value,'Choose') || strcmp(YearDropDown_value,'None')
                app.(topBtm.SiteDropDown).Items = {'None'};
            else
                yearFolder = fullfile(app.(topBtm.FolderEditField).Value,...
                    app.(topBtm.YearDropDown).Value);
                if isfolder(yearFolder)
                    siteFolders = dir(yearFolder);
                    siteFolders = siteFolders(3:end);
                    siteFolders = struct2table(siteFolders);
                    n = height(siteFolders);
                    Items = cell(1,n+1);
                    Items(1) = {'Choose'};
                    if n==1
                        Items(2) = {siteFolders.name};
                    else
                        Items(2:end) = siteFolders.name';
                    end
                    app.(topBtm.SiteDropDown).Items = Items;
                else
                    app.(topBtm.SiteDropDown).Items = {'None'};
                end
            end
            app.(topBtm.SiteDropDown).Value = app.(topBtm.SiteDropDown).Items(1);
        end
        
        function update_DatatypeDropDown(app,topBtm)
            app.(topBtm.DatatypeDropDown).Enable = 'off';
            app.(topBtm.LevelButtonGroup).SelectedObject = app.(topBtm.RawButton);
            app.(topBtm.RawButton).Enable = "off";
            app.(topBtm.Stage1Button).Enable = "off";
            app.(topBtm.Stage2Button).Enable = "off";
            app.(topBtm.TracesListBox).Enable = 'off';
            app.(topBtm.TracesListBox).Items = {'None'};
            app.(topBtm.TracesListBox).Value = app.(topBtm.TracesListBox).Items(1);
            
            % Get rid of plot data if it exists
            kill_trace(app,topBtm.value)

            SiteDropDown_value = app.(topBtm.SiteDropDown).Value;

            if strcmp(SiteDropDown_value,'Choose') || strcmp(SiteDropDown_value,'None')
                app.(topBtm.DatatypeDropDown).Items = {'None'};
            else
                siteFolder = fullfile(app.(topBtm.FolderEditField).Value,...
                    app.(topBtm.YearDropDown).Value,SiteDropDown_value);
                if isfolder(siteFolder)
                    datatypeFolders = dir(siteFolder);
                    datatypeFolders = datatypeFolders(3:end);
                    datatypeFolders = struct2table(datatypeFolders);
                    idx = datatypeFolders.isdir==1;
                    datatypeFolders = datatypeFolders.name(idx,:);
                    n = size(datatypeFolders,1);
                    Items = cell(1,n+1);
                    Items(1) = {'Choose'};
                    if n==1
                        if ischar(datatypeFolders)
                            Items(2) = {datatypeFolders};
                        else
                            Items(2) = datatypeFolders;
                        end
                    else
                        Items(2:end) = datatypeFolders';
                    end
                    app.(topBtm.DatatypeDropDown).Items = Items;
                else
                    app.(topBtm.DatatypeDropDown).Items = {'None'};
                end
            end
            app.(topBtm.DatatypeDropDown).Value = app.(topBtm.DatatypeDropDown).Items(1);
            app.(topBtm.DatatypeDropDown).Enable = 'on';
        end

        %--- Called when Year/Site/Data drop downs are changed ---%
        function update_LevelBox(app,topBtm)
            app.(topBtm.RawButton).Enable = "off";
            app.(topBtm.Stage1Button).Enable = "off";
            app.(topBtm.Stage2Button).Enable = "off";
            app.(topBtm.LevelButtonGroup).SelectedObject = app.(topBtm.RawButton);

            datatype_DropDown_value = app.(topBtm.DatatypeDropDown).Value;

            % Get rid of plot data if it exists
            kill_trace(app,topBtm.value)

             if strcmp(datatype_DropDown_value,'Choose') || strcmp(datatype_DropDown_value,'None')
                app.(topBtm.TracesListBox).Items = {'None'};
                app.(topBtm.TracesListBox).Enable = 'off';
             else
                 datatypeFolder = fullfile(app.(topBtm.FolderEditField).Value,...
                    app.(topBtm.YearDropDown).Value, app.(topBtm.SiteDropDown).Value, datatype_DropDown_value);

                 % Change radio button text dependent on data type selected
                 if strcmp(datatype_DropDown_value,'Clean')
                     app.(topBtm.RawButton).Text = 'Stage2';
                     app.(topBtm.Stage1Button).Text = 'Stage3';
                     app.(topBtm.Stage2Button).Text = 'Stage3+';
                 else
                     app.(topBtm.RawButton).Text = 'Raw';
                     app.(topBtm.Stage1Button).Text = 'Stage1';
                     app.(topBtm.Stage2Button).Text = '';
                 end

                 if isfolder(datatypeFolder)
                    % Check folders before turning on other radio buttons
                    if strcmp(datatype_DropDown_value,'Clean')
                        % Check for second stage folder
                        if isfolder(fullfile(datatypeFolder,'SecondStage'))
                            app.(topBtm.RawButton).Enable ="on";
                            datalevelFolder = fullfile(datatypeFolder,'SecondStage');
                        end
                        
                        % Check for third stage folder
                        if isfolder(fullfile(datatypeFolder,'ThirdStage'))
                            app.(topBtm.Stage1Button).Enable ="on";
                        end

                        % Check for third stage default Ustar folder
                        if isfolder(fullfile(datatypeFolder,'ThirdStage_Default_Ustar'))
                            app.(topBtm.Stage2Button).Enable ="on";
                        end
                    else
                        datalevelFolder = datatypeFolder;

                        % Turn on 'Raw' button by default
                        app.(topBtm.RawButton).Enable = "on";

                        % Check for Stage1 folder before activating button
                        if isfolder(fullfile(datatypeFolder,'Clean'))
                            app.(topBtm.Stage1Button).Enable ="on";
                        end
                    end

                    Items = update_TracesItems(app,datalevelFolder);

                    app.(topBtm.TracesListBox).Items = Items;
                    app.(topBtm.TracesListBox).Enable = 'on';
                 else
                    app.(topBtm.TracesListBox).Items = {'None'};
                    app.(topBtm.TracesListBox).Enable = 'off';
                 end
             end
             
             app.(topBtm.TracesListBox).Value = app.(topBtm.TracesListBox).Items(1);
        end
        
        
        function update_TracesListBox(app,topBtm)
            selectedButton = app.(topBtm.LevelButtonGroup).SelectedObject;
            
            commonRoot = fullfile(app.(topBtm.FolderEditField).Value,...
                    app.(topBtm.YearDropDown).Value, app.(topBtm.SiteDropDown).Value);
            if strcmp(selectedButton.Text,'Raw')
                datalevelFolder = fullfile(commonRoot,...
                    app.(topBtm.DatatypeDropDown).Value);
            elseif strcmp(selectedButton.Text,'Stage1')
                datalevelFolder = fullfile(commonRoot,...
                    app.(topBtm.DatatypeDropDown).Value,'Clean');
            elseif strcmp(selectedButton.Text,'Stage2')
                datalevelFolder = fullfile(commonRoot,...
                    'Clean','SecondStage');
            elseif strcmp(selectedButton.Text,'Stage3')
                datalevelFolder = fullfile(commonRoot,...
                    'Clean','ThirdStage');
            elseif strcmp(selectedButton.Text,'Stage3+')
                datalevelFolder = fullfile(commonRoot,...
                    'Clean','ThirdStage_Default_Ustar');
            end

            Items =  update_TracesItems(app,datalevelFolder);
            
            app.(topBtm.TracesListBox).Items = Items;
            app.(topBtm.TracesListBox).Value = app.(topBtm.TracesListBox).Items(1);

            if isfolder(datalevelFolder)
                year = str2double(app.(topBtm.YearDropDown).Value);
                tv = read_bor(fullfile(datalevelFolder,'clean_tv'),8,[],year);
            
                % convert time vector to Matlab's datetime
                tv_dt = datetime(tv,'ConvertFrom','datenum');

                ydata = zeros(size(tv));

                plot(app.(topBtm.UIAxes_primary), tv_dt, ydata)
                zoom(app.(topBtm.UIAxes_primary),'on')
                
                % Zoom out and unlink axes of secondary plot
                zoom(app.(topBtm.UIAxes_secondary),'out')
                linkaxes([app.UIAxes app.UIAxes2],'off')
             end
        end
        
        function update_plotWindow(app,topBtm)
            value = app.(topBtm.TracesListBox).Value;
            selectedButton = app.(topBtm.LevelButtonGroup).SelectedObject;
            
            commonRoot = fullfile(app.(topBtm.FolderEditField).Value,...
                    app.(topBtm.YearDropDown).Value, app.(topBtm.SiteDropDown).Value);

            if strcmp(selectedButton.Text,'Raw')
                databasePth = fullfile(commonRoot,...
                    app.(topBtm.DatatypeDropDown).Value);
            elseif strcmp(selectedButton.Text,'Stage1')
                databasePth = fullfile(commonRoot,...
                    app.(topBtm.DatatypeDropDown).Value,'Clean');
            elseif strcmp(selectedButton.Text,'Stage2')
                databasePth = fullfile(commonRoot,...
                    'Clean','SecondStage');
            elseif strcmp(selectedButton.Text,'Stage3')
                databasePth = fullfile(commonRoot,...
                    'Clean','ThirdStage');
            elseif strcmp(selectedButton.Text,'Stage3+')
                databasePth = fullfile(commonRoot,...
                    'Clean','ThirdStage_Default_Ustar');
            end

            year = str2double(app.(topBtm.YearDropDown).Value);
            tv = read_bor(fullfile(databasePth,'clean_tv'),8,[],year);
            
            % convert time vector to Matlab's datetime
            tv_dt = datetime(tv,'ConvertFrom','datenum');

            if strcmp(value,'None')
                ydata = zeros(size(tv));
            else
                ydata = read_bor(fullfile(databasePth,value),[],[],year);
                ydata(ydata==-9999) = NaN;
            end
            
            plot(app.(topBtm.UIAxes_primary), tv_dt, ydata)
            ylabel(app.(topBtm.UIAxes_primary), regexprep(value,'_',' '))
            zoom(app.(topBtm.UIAxes_primary),'out')
            linkaxes([app.UIAxes app.UIAxes2],'off')
            
            n_missing = sum(isnan(ydata));
            title(app.(topBtm.UIAxes_primary), char(['# samples missing = ' num2str(uint16(n_missing))]))

            if strcmp(app.YearDropDown.Value,app.YearDropDown_2.Value)
                % Only link axes if it's the same year.
                zoom(app.(topBtm.UIAxes_secondary),'out')
                linkaxes([app.UIAxes app.UIAxes2],'x')
            end

        end
        
        function trace_str = loadDbase(app,folder_base,site_str,year_str,stage_button)
            nRows = length(app.dBase);
            flag_database_loaded = 0;
            
            for i=1:nRows
                check.folder = strcmp(app.dBase(i).Folder,folder_base);
                check.site = strcmp(app.dBase(i).Site,site_str);
                check.year = strcmp(app.dBase(i).Year,year_str);
                check.stage = app.dBase(i).Stage == stage_button;

                check.sum = check.folder + check.site + check.year + check.stage;
                if check.sum==4
                    trace_str = app.dBase(i).trace_str;
                    flag_database_loaded = 1;
                end
            end
            
            if flag_database_loaded==0 % Default
                row = nRows + 1;
                app.dBase(row).Folder = folder_base;
                app.dBase(row).Site = site_str;
                app.dBase(row).Year = year_str;
                app.dBase(row).Stage = stage_button;

                try
                    % Load database
                    trace_str = fr_cleaning_siteyear(str2double(year_str),site_str,stage_button);

                    % Reduce size of trace_str in memory
                    trace_str = rmfield(trace_str,'DOY');
                    nRows_trace_str = length(trace_str);
                    for i=1:nRows_trace_str
                        trace_str(i).timeVector = single(trace_str(i).timeVector);
                        trace_str(i).data = single(trace_str(i).data);
                        trace_str(i).data_old = single(trace_str(i).data_old);
                    end
                catch
                    trace_str = struct();
                    errordlg('Failed to load database.')
                end

                app.dBase(row).trace_str = trace_str;
            end
            
        end
        
        function update_plotParents_font(app,topBtm)
            addStyle(app.TraceanalysisDropDown,app.uiFont_off,"item",3) % Default to 'off' colour

            if topBtm.value==1
                selectedButton = app.LevelButtonGroup_top.SelectedObject;
            elseif topBtm.value==2
                selectedButton = app.LevelButtonGroup_btm.SelectedObject;
            end

            % Don't need to check if [Level] is enabled since when
            %   disabled, it defaults to 'Raw'.
            if strcmp(selectedButton.Text,'Stage1')
                addStyle(app.TraceanalysisDropDown,app.uiFont_on,"item",3)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isfile('lastFolderUsed.ini')
                fid = fopen('lastFolderUsed.ini');
                results = fscanf(fid,'%s');
                fclose(fid);
                
                app.FolderEditField.Value = results;
                FolderEditFieldValueChanged(app)
                
                app.FolderEditField_2.Value = results;
                FolderEditField_2ValueChanged(app)
            end

            % Change font color of drop down list items which aren't active
            addStyle(app.TraceanalysisDropDown,app.uiFont_off,"item",5)
            addStyle(app.TraceanalysisDropDown,app.uiFont_off,"item",3)

            % Initial dBase structure for use with ta_plot_parents.m
            app.dBase.Folder = 'C:\test';
            app.dBase.Site = 'XXX';
            app.dBase.Year = '1901';
            app.dBase.Stage = 0;
            app.dBase.trace_str = struct();

            if ~isfile('Browse_16.png')
                img = [0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0
102	102	102	102	102	102	0	0	0	0	0	0	0	0	0	0
102	102	102	102	102	102	0	102	102	102	102	102	102	102	102	102
102	102	102	102	102	102	0	0	0	0	0	0	0	0	0	0
102	102	102	102	102	102	102	102	102	102	102	102	102	102	102	102
102	102	102	102	102	102	102	102	102	102	102	102	102	102	102	102
102	102	102	102	102	102	102	102	102	102	102	102	102	102	102	102
102	102	102	102	102	102	102	102	102	102	102	102	102	102	102	102
102	102	102	102	102	102	102	102	102	102	102	102	102	102	102	102
102	102	102	102	102	102	102	102	102	102	102	102	102	102	102	102
102	102	102	102	102	102	102	102	102	102	102	102	102	102	102	102
102	102	102	102	102	102	102	102	102	102	102	102	102	102	102	102
102	102	102	102	102	102	102	102	102	102	102	102	102	102	102	102
102	102	102	102	102	102	102	102	102	102	102	102	102	102	102	102
0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0
0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0];
                transp = [0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0
255	255	255	255	255	255	0	0	0	0	0	0	0	0	0	0
255	255	255	255	255	255	0	255	255	255	255	255	255	255	255	255
255	255	255	255	255	255	0	0	0	0	0	0	0	0	0	0
255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255
255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255
255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255
255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255
255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255
255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255
255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255
255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255
255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255
255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255
0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0
0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0];
                imwrite(uint8(img),'Browse_16.png','Alpha',transp./255)
            end

            if ~isfile('Help_24.png')
                img = uint8(zeros(24,24,3));
                img(:,:,1) = [0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0
0	0	0	0	0	0	0	18	60	84	96	96	96	96	84	60	18	0	0	0	0	0	0	0
0	0	0	0	0	12	70	131	224	249	255	255	255	255	248	220	129	70	12	0	0	0	0	0
0	0	0	0	28	84	224	255	255	255	255	255	253	251	252	255	255	218	84	28	0	0	0	0
0	0	0	27	106	249	255	255	255	255	255	255	251	247	243	239	245	255	245	104	27	0	0	0
0	0	10	76	249	255	255	255	255	255	255	254	250	246	242	238	233	234	249	244	76	10	0	0
0	0	58	221	255	255	255	255	255	189	71	48	51	77	195	236	232	228	226	249	211	58	0	0
0	14	116	255	255	255	255	255	189	46	48	51	54	57	60	192	231	227	223	224	252	110	14	0
0	44	218	253	253	254	255	255	71	48	114	239	129	60	63	118	229	225	221	217	228	205	44	0
0	58	241	247	249	250	251	251	48	51	225	244	245	132	65	110	227	223	220	213	201	227	58	0
0	62	249	243	245	246	246	246	250	250	243	240	238	108	68	175	225	221	217	207	196	224	62	0
0	70	243	239	241	242	242	242	241	240	239	149	107	68	80	220	222	219	212	201	189	202	58	0
0	67	237	235	236	237	238	238	237	236	235	65	68	134	208	223	219	214	204	194	183	187	54	0
0	51	229	231	232	233	233	233	233	232	231	68	70	234	225	219	215	206	196	186	175	164	51	0
0	41	192	227	228	229	229	229	229	228	226	70	73	221	219	214	206	197	187	177	166	136	41	0
0	27	144	223	224	224	225	225	224	223	222	197	196	208	211	204	196	187	178	168	158	102	27	0
0	7	61	219	220	220	220	220	220	219	218	75	77	176	200	193	185	177	168	158	148	50	7	0
0	0	26	134	212	214	215	214	213	211	209	77	80	172	189	182	174	166	157	148	88	26	0	0
0	0	4	29	168	201	202	202	200	199	196	224	222	192	177	170	163	155	147	108	29	4	0	0
0	0	0	9	38	159	188	188	187	185	183	179	175	170	164	158	151	140	103	32	9	0	0	0
0	0	0	0	8	23	109	175	174	172	169	166	162	157	152	146	129	73	23	8	0	0	0	0
0	0	0	0	0	3	15	38	99	133	156	153	149	142	109	73	30	15	3	0	0	0	0	0
0	0	0	0	0	0	0	4	13	18	20	20	20	20	18	13	4	0	0	0	0	0	0	0
0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0];

                img(:,:,2) = [0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0
0	0	0	0	0	0	0	29	97	135	154	154	154	154	135	97	29	0	0	0	0	0	0	0
0	0	0	0	0	19	112	174	235	251	255	255	255	255	250	232	172	112	19	0	0	0	0	0
0	0	0	0	45	136	234	255	255	255	255	255	254	252	253	255	255	229	136	45	0	0	0	0
0	0	0	44	153	251	255	255	255	255	255	255	252	249	246	244	247	255	248	152	44	0	0	0
0	0	17	126	251	255	255	255	255	255	255	254	251	248	245	242	239	240	251	247	126	17	0	0
0	0	96	231	255	255	255	255	255	193	81	59	63	88	200	241	238	235	233	250	223	96	0	0
0	23	154	255	255	255	255	255	193	57	59	63	66	69	73	199	237	234	231	231	253	149	23	0
0	74	228	253	253	254	255	255	81	59	122	240	137	73	76	132	236	233	230	227	234	217	74	0
0	99	245	249	250	251	252	252	59	63	227	247	247	140	83	127	234	232	229	224	214	234	99	0
0	108	251	246	247	248	249	249	252	251	246	244	242	123	89	187	233	230	227	219	209	230	108	0
0	112	246	243	244	245	245	245	245	244	243	158	122	89	105	229	231	228	223	213	203	212	102	0
0	107	242	240	241	242	242	242	242	241	240	83	89	152	216	232	229	225	216	207	197	200	97	0
0	92	236	237	238	239	239	239	239	238	237	89	96	240	233	229	225	218	209	200	191	181	92	0
0	76	206	234	235	236	236	236	235	235	234	96	102	230	228	225	217	210	201	193	184	158	76	0
0	51	165	231	232	232	233	233	232	232	231	208	209	220	222	216	209	201	193	185	176	130	51	0
0	14	95	228	229	229	230	229	229	229	228	109	115	194	213	206	200	192	185	177	168	86	14	0
0	0	53	156	223	224	225	225	224	222	220	115	121	192	203	197	190	183	176	168	118	53	0	0
0	0	8	62	185	213	214	214	213	211	209	231	229	204	192	187	181	174	167	137	62	8	0	0
0	0	0	19	69	176	202	202	201	200	198	195	191	187	182	176	170	162	133	65	19	0	0	0
0	0	0	0	17	52	132	191	190	188	186	183	180	176	171	166	155	105	52	17	0	0	0	0
0	0	0	0	0	6	38	66	122	154	175	172	169	163	136	104	61	38	6	0	0	0	0	0
0	0	0	0	0	0	0	9	31	44	50	50	50	50	44	31	9	0	0	0	0	0	0	0
0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0];

                img(:,:,3) = [0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0
0	0	0	0	0	0	0	36	119	166	189	189	189	189	166	119	36	0	0	0	0	0	0	0
0	0	0	0	0	23	139	201	242	253	255	255	255	255	251	239	199	139	23	0	0	0	0	0
0	0	0	0	56	168	241	255	255	255	255	255	254	253	253	255	255	236	168	56	0	0	0	0
0	0	0	55	184	252	255	255	255	255	255	255	252	250	247	245	248	255	249	183	55	0	0	0
0	0	21	158	252	255	255	255	255	255	255	254	252	249	246	244	241	241	251	249	158	21	0	0
0	0	123	238	255	255	255	255	255	198	96	77	81	104	206	243	240	237	236	251	231	123	0	0
0	30	181	255	255	255	255	255	198	74	77	81	84	88	92	205	239	237	234	234	253	176	30	0
0	95	234	253	253	254	255	255	96	77	134	242	149	92	96	148	238	235	233	230	237	225	95	0
0	129	247	250	251	252	252	252	77	81	230	247	249	153	105	146	237	234	232	227	219	238	129	0
0	142	251	247	248	249	249	249	252	252	247	245	244	140	114	198	235	233	230	224	216	235	142	0
0	143	247	245	246	246	246	246	246	245	244	169	139	114	129	232	233	231	226	219	211	219	136	0
0	139	244	242	243	243	244	244	243	243	242	105	114	168	223	234	232	228	221	214	207	210	131	0
0	126	238	239	240	241	241	241	240	240	239	114	122	241	235	231	229	223	216	209	201	194	126	0
0	105	215	237	237	238	238	238	238	237	236	122	131	233	231	228	222	216	210	203	196	175	105	0
0	72	182	234	234	235	235	235	235	234	234	216	217	225	226	221	216	210	203	197	190	153	72	0
0	21	125	231	232	232	232	232	232	231	231	139	148	207	219	214	209	203	197	190	184	119	21	0
0	0	78	172	227	228	228	228	228	226	224	148	157	206	211	206	201	196	190	184	146	78	0	0
0	0	12	93	196	219	220	220	219	218	216	234	233	213	203	198	193	188	182	164	93	12	0	0
0	0	0	29	101	189	211	211	210	209	207	204	202	198	194	190	185	181	163	99	29	0	0	0
0	0	0	0	28	83	152	201	201	200	198	196	193	190	186	182	179	138	83	28	0	0	0	0
0	0	0	0	0	10	62	97	144	170	189	187	184	181	161	136	94	62	10	0	0	0	0	0
0	0	0	0	0	0	0	16	52	73	83	83	83	83	73	52	16	0	0	0	0	0	0	0
0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0];

                transp = [0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0
0	0	0	0	0	0	0	48	160	224	255	255	255	255	224	160	48	0	0	0	0	0	0	0
0	0	0	0	0	32	192	255	255	255	255	255	255	255	255	255	255	192	32	0	0	0	0	0
0	0	0	0	80	240	255	255	255	255	255	255	255	255	255	255	255	255	240	80	0	0	0	0
0	0	0	80	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	80	0	0	0
0	0	32	240	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	240	32	0	0
0	0	192	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	192	0	0
0	48	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	48	0
0	160	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	160	0
0	224	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	224	0
0	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	0
0	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	0
0	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	0
0	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	0
0	224	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	224	0
0	160	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	160	0
0	48	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	48	0
0	0	192	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	192	0	0
0	0	32	240	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	240	32	0	0
0	0	0	80	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	255	80	0	0	0
0	0	0	0	80	240	255	255	255	255	255	255	255	255	255	255	255	255	240	80	0	0	0	0
0	0	0	0	0	32	192	255	255	255	255	255	255	255	255	255	255	192	32	0	0	0	0	0
0	0	0	0	0	0	0	48	160	224	255	255	255	255	224	160	48	0	0	0	0	0	0	0
0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0	0];
                imwrite(img,'Help_24.png','Alpha',transp./255)
            end
        end

        % Value changed function: FolderEditField
        function FolderEditFieldValueChanged(app, event)
            topBtm = get_topBtm_struct(app,1);
            update_YearDropDown(app,topBtm)
        end

        % Value changed function: YearDropDown
        function YearDropDownValueChanged(app, event)
            topBtm = get_topBtm_struct(app,1);
            update_SiteDropDown(app,topBtm)
        end

        % Value changed function: SiteDropDown
        function SiteDropDownValueChanged(app, event)
            topBtm = get_topBtm_struct(app,1);
            update_DatatypeDropDown(app,topBtm)
        end

        % Value changed function: DatatypeDropDown
        function DatatypeDropDownValueChanged(app, event)
            topBtm = get_topBtm_struct(app,1);
            update_LevelBox(app,topBtm)

             % % load time vector
             % year = str2double(app.YearDropDown.Value);
             % databasePth = fullfile(app.FolderEditField.Value, app.YearDropDown.Value,...
             %     app.SiteDropDown.Value, app.DatatypeDropDown.Value);
             % 
             % if isfolder(databasePth)
             %    tv = read_bor(fullfile(databasePth,'clean_tv'),8,[],year);
             % 
             %    % convert time vector to Matlab's datetime
             %    tv_dt = datetime(tv,'ConvertFrom','datenum');
             % 
             %    ydata = zeros(size(tv));
             % 
             %    plot(app.UIAxes, tv_dt, ydata)
             %    zoom(app.UIAxes,'on')
             %    % plot(app.UIAxes2, tv_dt, ydata)
             %    % zoom(app.UIAxes2,'on')
             %    try
             %        zoom(app.UIAxes2,'reset')
             %        % linkaxes([app.UIAxes app.UIAxes2],'x')
             %    end
             % end
        end

        % Value changed function: TracestoppanelListBox
        function TracestoppanelListBoxValueChanged(app, event)
            topBtm = get_topBtm_struct(app,1);
            update_plotWindow(app, topBtm)
        end

        % Value changed function: TracesbottompanelListBox
        function TracesbottompanelListBoxValueChanged(app, event)
            topBtm = get_topBtm_struct(app,2);
            update_plotWindow(app, topBtm)
        end

        % Value changed function: FolderEditField_2
        function FolderEditField_2ValueChanged(app, event)
            topBtm = get_topBtm_struct(app,2);
            update_YearDropDown(app,topBtm)
        end

        % Value changed function: YearDropDown_2
        function YearDropDown_2ValueChanged(app, event)
            topBtm = get_topBtm_struct(app,2);
            update_SiteDropDown(app,topBtm)
        end

        % Value changed function: SiteDropDown_2
        function SiteDropDown_2ValueChanged(app, event)
            topBtm = get_topBtm_struct(app,2);
            update_DatatypeDropDown(app,topBtm)
        end

        % Value changed function: DatatypeDropDown_2
        function DatatypeDropDown_2ValueChanged(app, event)
            topBtm = get_topBtm_struct(app,2);
            update_LevelBox(app,topBtm)
        end

        % Selection changed function: LevelButtonGroup_btm
        function LevelButtonGroup_btmSelectionChanged(app, event)
            topBtm = get_topBtm_struct(app,2);
            update_plotParents_font(app,topBtm)
            update_TracesListBox(app,topBtm)
        end

        % Button pushed function: CompareTraces
        function CompareTracesPushed(app, event)

            [tv, input1, input2, plotFlag] = getTrace_inputs(app);
            
            % Only compare traces from the same year
            input1_year = app.YearDropDown.Value;
            input2_year = app.YearDropDown_2.Value;

            years = (1900:2099)';
            years_str = num2str(years);
            valid_years = cellstr(years_str);

            if sum(strcmp(valid_years,input1_year))==1 &&...
                    sum(strcmp(valid_years,input2_year))==1
                if ~strcmp(input1_year,input2_year)
                    msgStr = 'Years must be the same.';
                    plotFlag = 0;
                end

                if strcmp(app.TracestoppanelListBox.Value,'None') ||...
                        strcmp(app.TracesbottompanelListBox.Value,'None')
                    msgStr = 'Choose valid traces.';
                    plotFlag = 0;
                end
            else
                msgStr = 'Choose a valid year.';
                plotFlag = 0;
            end

            if plotFlag==1
                simple_trace_comparison(tv, input1, input2)
            else
                msgStruct.Interpreter = 'tex';
                msgStruct.WindowStyle = 'non-modal';
                errordlg(char(['\fontsize{12} ' msgStr]),'',msgStruct)
            end
        end

        % Button pushed function: SummarizeButton
        function SummarizeButtonPushed(app, event)
            plotFlag = 0;
            msgStr = '';
            selectedButton = app.LevelButtonGroup_btm.SelectedObject;
            traceValue = app.TraceSpinner.Value;
            
            if traceValue==1
                msgStr = 'Trace #1: ';
                % Folder for input #1
                % if strcmp(selectedButton.Text,'Raw')
                    datalevelFolder = fullfile(app.FolderEditField.Value,...
                        app.YearDropDown.Value, app.SiteDropDown.Value,...
                        app.DatatypeDropDown.Value);
                % elseif strcmp(selectedButton.Text,'Stage1')
                %     datalevelFolder_1 = fullfile(app.FolderEditField_2.Value,...
                %         app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                %         app.DatatypeDropDown_2.Value,'Clean');
                % end
            elseif traceValue==2
                msgStr = 'Trace #2: ';

                % Folder for input #2
                if strcmp(selectedButton.Text,'Raw')
                    datalevelFolder = fullfile(app.FolderEditField_2.Value,...
                        app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                        app.DatatypeDropDown_2.Value);
                elseif strcmp(selectedButton.Text,'Stage1')
                    datalevelFolder = fullfile(app.FolderEditField_2.Value,...
                        app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                        app.DatatypeDropDown_2.Value,'Clean');
                elseif strcmp(selectedButton.Text,'Stage2')
                    datalevelFolder = fullfile(app.FolderEditField_2.Value,...
                        app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                        'Clean','SecondStage');
                elseif strcmp(selectedButton.Text,'Stage3')
                    datalevelFolder = fullfile(app.FolderEditField_2.Value,...
                        app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                        'Clean','ThirdStage');
                elseif strcmp(selectedButton.Text,'Stage3+')
                    datalevelFolder = fullfile(app.FolderEditField_2.Value,...
                        app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                        'Clean','ThirdStage_Default_Ustar');
                end
            end

            if isfolder(datalevelFolder)
                if traceValue==1
                    year = str2double(app.YearDropDown.Value);
                    varname = app.TracestoppanelListBox.Value;
                elseif traceValue==2
                    year = str2double(app.YearDropDown_2.Value);
                    varname = app.TracesbottompanelListBox.Value;
                end

                tv = read_bor(fullfile(datalevelFolder,'clean_tv'),8,[],year);
                
                if isfile(fullfile(datalevelFolder,varname)) && ~isnan(year)
                    input1 = read_bor(fullfile(datalevelFolder,varname),[],[],year);
                    input1(input1==-9999) = NaN;
                    plotFlag = 1;
                end
            end

            if plotFlag==1
                simple_trace_summary(tv, input1)
            else
                msgStruct.Interpreter = 'tex';
                msgStruct.WindowStyle = 'non-modal';
                msgStr = [msgStr 'No trace to summarize.'];
                errordlg(char(['\fontsize{12} ' msgStr]),'',msgStruct)
            end
        end

        % Size changed function: Subpanel
        function SubpanelSizeChanged(app, event)
            position = app.Subpanel.Position;
            
        end

        % Button pushed function: GetDirButton_1
        function GetDirButton_1Pushed(app, event)
            fldr = uigetdir([],'Select database folder');

            app.FolderEditField.Value = fldr;
            FolderEditFieldValueChanged(app)
        end

        % Button pushed function: GetDirButton_2
        function GetDirButton_2Pushed(app, event)
            fldr = uigetdir([],'Select database folder');

            app.FolderEditField_2.Value = fldr;
            FolderEditField_2ValueChanged(app)
        end

        % Selection changed function: LevelButtonGroup_top
        function LevelButtonGroup_topSelectionChanged(app, event)
            topBtm = get_topBtm_struct(app,1);
            update_plotParents_font(app,topBtm)
            update_TracesListBox(app,topBtm)
        end

        % Button pushed function: CompareStages
        function CompareStagesPushed(app, event)

            [tv, input1, input2, plotFlag] = getTrace_inputs(app);

            yearX = datevec(tv(1));
            siteID = app.SiteDropDown.Value;
            disp(siteID)
            if plotFlag==1 & ~isempty(input1) & ~isempty(input2)
                fr_compare_database_traces2(siteID,yearX,[],[],[],...
                    [],[],input1,input2)
            end
        end

        % Callback function: HelpPushTool
        function HelpPushToolClicked(app, event)
            message = {'This app was initiallly designed to quickly view traces in the database and select potential alternate traces to use for gap filling.',...
                '',...
                'Type in a siteID\Database\ folder name or use the browse button to select the directory to search for a trace.',...
                '',...
                'The [Summarize] button generates several summary plots for a selected trace in a new window. The trace that is summrized is based on the value in the [Trace] field (i.e. 1-top; 2-bottom).',...
                '',...
                'The [Compare] button generates several comparison plots for the two selected traces in a new window. It only allows comparisons of traces from the same year (if working properly). Different sites, data types, and levels can be compared.',...
                '',...
                'For both [Compare] and [Summarize], only the upper left panel in the new window will be interactive (i.e. zoom/pan). The data in the other sub-panels will automatically update based on what is visible in the main window.',...
                '',...
                'The shaded out button will allow you to examine changes in a trace across stages using Zoran`s fr_compare_database_traces(). Since trace names might differ between the raw database and subsequent stages, robust error checking is still in progress...',...
                '',...
                '',...
                'In the future, the help button will ideally open documentation on GitHub.',...
                ''};

            msgbox(message,'Overview','modal');
        end

        % Value changed function: MatchSwitch
        function MatchSwitchValueChanged(app, event)
            value = app.MatchSwitch.Value;
            flag = 0;
            if strcmp(value,'On')
                if strcmp(app.SiteDropDown.Value,'Choose') | strcmp(app.SiteDropDown.Value,'None')
                    app.MatchSwitch.Value = 'Off';
                    flag = 1;
                end

                if strcmp(app.YearDropDown.Value,'Choose') | strcmp(app.SiteDropDown.Value,'None')
                    app.MatchSwitch.Value = 'Off';
                    flag = 1;
                end

                if strcmp(app.FolderEditField.Value,'')
                    app.MatchSwitch.Value = 'Off';
                    flag = 1;
                end
                
                if flag==1
                    errordlg('Folder, Year, and/or Site not set for Trace #1')
                elseif flag == 0
                    app.FolderEditField_2.Enable = 'off';
                    app.YearDropDown_2.Enable = 'off';
                    app.SiteDropDown_2.Enable = 'off';
                    
                    app.YearDropDown_2.Items = app.YearDropDown.Items;
                    app.YearDropDown_2.Value = app.YearDropDown.Value;
                    app.SiteDropDown_2.Items = app.SiteDropDown.Items;
                    app.SiteDropDown_2.Value = app.SiteDropDown.Value;
                    app.FolderEditField_2.Value = app.FolderEditField.Value;
                    
                    topBtm = get_topBtm_struct(app,2);
                    update_DatatypeDropDown(app,topBtm)
                    % update_LevelBox(app,topBtm)
                    % 
                    % topBtm = get_topBtm_struct(app,2);
                    % update_TracesListBox(app,topBtm)
                end
            elseif strcmp(value,'Off')
                app.FolderEditField_2.Enable = 'on';
                app.YearDropDown_2.Enable = 'on';
                app.SiteDropDown_2.Enable = 'on';
            end
        end

        % Value changed function: TraceanalysisDropDown
        function TraceanalysisDropDownValueChanged(app, event)
            value = app.TraceanalysisDropDown.Value;
            app.TraceanalysisDropDown.Enable = 'off';
            msgStruct.Interpreter = 'tex';
            msgStruct.WindowStyle = 'non-modal';

            if strcmpi(value,'Compare stages')
                if 1==2 % Not ready yet
                    [tv, input1, input2] = getTrace_inputs(app);
    
                    yearX = datevec(tv(1));
                    siteID = app.SiteDropDown.Value;
                    disp(siteID)
                    if ~isempty(input1) & ~isempty(input2)
                        fr_compare_database_traces2(siteID,yearX,[],[],[],...
                            [],[],input1,input2)
                    end
                end
            elseif strcmpi(value,'Compare traces')
                msgStr = '';
                [tv, input1, input2] = getTrace_inputs(app);

                % Only compare traces from the same year
                input1_year = app.YearDropDown.Value;
                input2_year = app.YearDropDown_2.Value;
    
                years = (1900:2099)';
                years_str = num2str(years);
                valid_years = cellstr(years_str);
    
                if sum(strcmp(valid_years,input1_year))==1 &&...
                        sum(strcmp(valid_years,input2_year))==1
                    plotFlag = 1;

                    if ~strcmp(input1_year,input2_year)
                        msgStr = [msgStr 'Years must be the same. '];
                        plotFlag = 0;
                    end
    
                    if strcmp(app.TracestoppanelListBox.Value,'None') &&...
                            strcmp(app.TracesbottompanelListBox.Value,'None')
                        msgStr = [msgStr 'No traces selected. '];
                        plotFlag = 0;
                    elseif strcmp(app.TracestoppanelListBox.Value,'None')
                        msgStr = [msgStr 'Trace #1 not selected. '];
                        plotFlag = 0;
                    elseif strcmp(app.TracesbottompanelListBox.Value,'None')
                        msgStr = [msgStr 'Trace #2 not selected. '];
                        plotFlag = 0;
                    end
                else
                    msgStr = [msgStr 'Choose a valid year -- '];
                    if sum(strcmp(valid_years,input1_year))~=1
                        msgStr = [msgStr 'Trace #1 -- '];
                    end
                    
                    if sum(strcmp(valid_years,input2_year))~=1
                        msgStr = [msgStr 'Trace #2 -- '];
                    end
                    plotFlag = 0;
                end
    
                if plotFlag==1
                    simple_trace_comparison(tv, input1, input2)
                else
                    errordlg(char(['\fontsize{12} ' msgStr]),'',msgStruct)
                end
            elseif strcmp(value,'Single trace')
                % selectedButton = app.LevelButtonGroup_btm.SelectedObject;
                traceValue = app.TraceSpinner.Value;

                if traceValue==1
                    msgStr = 'Trace #1: ';
                    [tv, input, ~] = getTrace_inputs(app);
                elseif traceValue==2
                    msgStr = 'Trace #2: ';
                    [tv, ~, input] = getTrace_inputs(app);
                end
    
                if ~isempty(input)
                    simple_trace_summary(tv, input)
                else
                    msgStr = [msgStr 'No trace to summarize.'];
                    errordlg(char(['\fontsize{12} ' msgStr]),'',msgStruct)
                end
            elseif strcmpi(value,'Plot parents')
                % Should everything be turned off while database retrieval
                % is occuring?
                msgStr = '';
                stage_button = -1;
                
                if app.TraceSpinner.Value==1
                    msgStr = [msgStr 'Trace #1: '];
                    topBtm = get_topBtm_struct(app,1);
                elseif app.TraceSpinner.Value==2
                    msgStr = [msgStr 'Trace #2: '];
                    topBtm = get_topBtm_struct(app,2);
                end
                
                varname = app.(topBtm.TracesListBox).Value;
                folder_base = app.(topBtm.FolderEditField).Value;
                year_str = app.(topBtm.YearDropDown).Value;
                site_str = app.(topBtm.SiteDropDown).Value;
                if app.(topBtm.Stage1Button).Value==1
                    if strcmp(app.(topBtm.Stage1Button).Text,'Stage1')
                        stage_button=1;
                    else
                        stage_button=3;
                    end
                elseif app.(topBtm.RawButton).Value==1
                    if strcmp(app.(topBtm.RawButton).Text,'Raw')
                        stage_button=0;
                    else
                        stage_button=2;
                    end
                elseif app.(topBtm.Stage2Button).Value==1
                    if strcmp(app.(topBtm.Stage2Button).Text,'')
                        stage_button=NaN;
                    else
                        stage_button=4;
                    end
                end

                % Need to update to handle when >Stage 2
                if stage_button==1
                    filename = char([site_str '_' year_str...
                        '_FirstStage_stats.mat']);

                    % Load database if first time for folder/site/year/stage
                    trace_str = loadDbase(app,folder_base,site_str,year_str,stage_button);
                elseif stage_button==0
                    % Raw stage doesn't have parents
                    filename = [];

                    msgStr = [msgStr 'Raw traces do not have parents.'];
                    errordlg(char(['\fontsize{12} ' msgStr]),'',msgStruct)
                elseif stage_button>=2
                    % Not yet implemented
                    filename = [];

                    msgStr = [msgStr 'Only implemented in stage #1 so far.'];
                    errordlg(char(['\fontsize{12} ' msgStr]),'',msgStruct)
                elseif stage_button==-1
                    msgStr = [msgStr 'There is an error in the code...'];
                    errordlg(char(['\fontsize{12} ' msgStr]),'',msgStruct)
                end

                % folder_load = fullfile(folder_base,'Calculation_Procedures',...
                %     'TraceAnalysis_ini',site_str,'Derived_Variables');
                % 
                % % Load in data structure
                % if ~isempty(filename)
                %     folder_file = fullfile(folder_load,filename);
                %     if isfile(folder_file)
                %         load(folder_file)
                %     else
                %         msgStr = [msgStr char(['File ' regexprep(filename,'_','\\_') ' not found.'])];
                %         errordlg(char(['\fontsize{12} ' msgStr]),'',msgStruct)
                %     end
                % end

                try
                    if ~isfield(trace_str,'data') || ~isfield(trace_str,'data_old') ||...
                            ~isfield(trace_str,'timeVector')
                        msgStr = 'Data structures missing.';
                        errordlg(char(['\fontsize{12} ' msgStr]),'',msgStruct)
                    else
                        indVar = 0;
                        for cntTraces = 1:length(trace_str)
                            if strcmp(trace_str(cntTraces).variableName,varname)
                                indVar = cntTraces;
                                break
                            end
                        end

                        if ~isempty(trace_str(indVar).ind_parents)
                            ta_plot_parents([],trace_str,varname);
                        else
                            % Add dialogue box to tell user trace has no parents
                            msgStr = char(['The selected [' varname...
                                '] trace has no parents.']);
                            errordlg(char(['\fontsize{12} ' msgStr]),'',msgStruct)
                        end
                    end
                end
            end

            app.TraceanalysisDropDown.Value = 'Choose';
            app.TraceanalysisDropDown.Enable = 'on';
        end

        % Value changed function: TraceSpinner
        function TraceSpinnerValueChanged(app, event)
            value = app.TraceSpinner.Value;
            topBtm = get_topBtm_struct(app,value);
            update_plotParents_font(app,topBtm)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.HelpPushTool = uipushtool(app.Toolbar);
            app.HelpPushTool.ClickedCallback = createCallbackFcn(app, @HelpPushToolClicked, true);
            app.HelpPushTool.Enable = 'off';
            app.HelpPushTool.Icon = 'Help_24.png';
            app.LeftLowerPanel = uipanel(app.UIFigure);
            app.TracesbottompanelListBoxLabel = uilabel(app.LeftLowerPanel);
            app.TracesbottompanelListBoxLabel.Enable = 'off';
            app.TracesbottompanelListBoxLabel.Text = 'Traces (bottom  panel)';
            app.TracesbottompanelListBox = uilistbox(app.LeftLowerPanel);
            app.TracesbottompanelListBox.Items = {'None'};
            app.TracesbottompanelListBox.ValueChangedFcn = createCallbackFcn(app, @TracesbottompanelListBoxValueChanged, true);
            app.TracesbottompanelListBox.Enable = 'off';
            app.TracesbottompanelListBox.Value = 'None';
            app.FolderEditField_2Label = uilabel(app.LeftLowerPanel);
            app.FolderEditField_2Label.Text = 'Folder';
            app.YearDropDown_2Label = uilabel(app.LeftLowerPanel);
            app.YearDropDown_2Label.Text = 'Year';
            app.SiteDropDown_2Label = uilabel(app.LeftLowerPanel);
            app.SiteDropDown_2Label.Text = 'Site';
            app.DatatypeDropDown_2Label = uilabel(app.LeftLowerPanel);
            app.DatatypeDropDown_2Label.Text = 'Data type';
            app.LevelButtonGroup_btm = uibuttongroup(app.LeftLowerPanel);
            app.LevelButtonGroup_btm.SelectionChangedFcn = createCallbackFcn(app, @LevelButtonGroup_btmSelectionChanged, true);
            app.LevelButtonGroup_btm.Title = 'Level';
            app.RawButton_btm = uiradiobutton(app.LevelButtonGroup_btm);
            app.RawButton_btm.Enable = 'off';
            app.RawButton_btm.Text = 'Raw';
            app.RawButton_btm.Value = true;
            app.Stage1Button_btm = uiradiobutton(app.LevelButtonGroup_btm);
            app.Stage1Button_btm.Enable = 'off';
            app.Stage1Button_btm.Text = 'Stage1';
            app.Stage2Button_btm = uiradiobutton(app.LevelButtonGroup_btm);
            app.Stage2Button_btm.Enable = 'off';
            app.Stage2Button_btm.Text = '';
            app.DatatypeDropDown_2 = uidropdown(app.LeftLowerPanel);
            app.DatatypeDropDown_2.Items = {'None'};
            app.DatatypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @DatatypeDropDown_2ValueChanged, true);
            app.DatatypeDropDown_2.Value = 'None';
            app.SiteDropDown_2 = uidropdown(app.LeftLowerPanel);
            app.SiteDropDown_2.Items = {'None'};
            app.SiteDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SiteDropDown_2ValueChanged, true);
            app.SiteDropDown_2.Value = 'None';
            app.YearDropDown_2 = uidropdown(app.LeftLowerPanel);
            app.YearDropDown_2.Items = {'2999'};
            app.YearDropDown_2.ValueChangedFcn = createCallbackFcn(app, @YearDropDown_2ValueChanged, true);
            app.YearDropDown_2.Value = '2999';
            app.FolderEditField_2 = uieditfield(app.LeftLowerPanel, 'text');
            app.FolderEditField_2.ValueChangedFcn = createCallbackFcn(app, @FolderEditField_2ValueChanged, true);
            app.FolderEditField_2.Tooltip = {'Type in database folder path.'};
            app.GetDirButton_2 = uibutton(app.LeftLowerPanel, 'push');
            app.GetDirButton_2.ButtonPushedFcn = createCallbackFcn(app, @GetDirButton_2Pushed, true);
            app.GetDirButton_2.Icon = 'Browse_16.png';
            app.GetDirButton_2.Tooltip = {'Select database folder'};
            app.GetDirButton_2.Text = '';
            app.MatchSwitchLabel = uilabel(app.LeftLowerPanel);
            app.MatchSwitchLabel.Tooltip = {'When ''On'', [Folder], [Year] and [Site] forced to match top '};
            app.MatchSwitchLabel.Text = 'Match';
            app.MatchSwitch = uiswitch(app.LeftLowerPanel, 'slider');
            app.MatchSwitch.ValueChangedFcn = createCallbackFcn(app, @MatchSwitchValueChanged, true);
            app.MatchSwitch.Tooltip = {'When ''On'', [Folder], [Year] and [Site] forced to match top panel.'};
            app.LeftUpperPanel = uipanel(app.UIFigure);
            app.SiteDropDownLabel = uilabel(app.LeftUpperPanel);
            app.SiteDropDownLabel.Text = 'Site';
            app.YearDropDownLabel = uilabel(app.LeftUpperPanel);
            app.YearDropDownLabel.Text = 'Year';
            app.TracestoppanelListBoxLabel = uilabel(app.LeftUpperPanel);
            app.TracestoppanelListBoxLabel.Enable = 'off';
            app.TracestoppanelListBoxLabel.Text = 'Traces (top panel)';
            app.TracestoppanelListBox = uilistbox(app.LeftUpperPanel);
            app.TracestoppanelListBox.Items = {'None'};
            app.TracestoppanelListBox.ValueChangedFcn = createCallbackFcn(app, @TracestoppanelListBoxValueChanged, true);
            app.TracestoppanelListBox.Enable = 'off';
            app.TracestoppanelListBox.Value = 'None';
            app.LevelButtonGroup_top = uibuttongroup(app.LeftUpperPanel);
            app.LevelButtonGroup_top.SelectionChangedFcn = createCallbackFcn(app, @LevelButtonGroup_topSelectionChanged, true);
            app.LevelButtonGroup_top.Title = 'Level';
            app.RawButton_top = uiradiobutton(app.LevelButtonGroup_top);
            app.RawButton_top.Enable = 'off';
            app.RawButton_top.Text = 'Raw';
            app.RawButton_top.Value = true;
            app.Stage1Button_top = uiradiobutton(app.LevelButtonGroup_top);
            app.Stage1Button_top.Enable = 'off';
            app.Stage1Button_top.Text = 'Stage1';
            app.Stage2Button_top = uiradiobutton(app.LevelButtonGroup_top);
            app.Stage2Button_top.Enable = 'off';
            app.Stage2Button_top.Text = '';
            app.FolderEditFieldLabel = uilabel(app.LeftUpperPanel);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.DatatypeDropDownLabel = uilabel(app.LeftUpperPanel);
            app.DatatypeDropDownLabel.Text = 'Data type';
            app.DatatypeDropDown = uidropdown(app.LeftUpperPanel);
            app.DatatypeDropDown.Items = {'None'};
            app.DatatypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DatatypeDropDownValueChanged, true);
            app.DatatypeDropDown.Value = 'None';
            app.SiteDropDown = uidropdown(app.LeftUpperPanel);
            app.SiteDropDown.Items = {'None'};
            app.SiteDropDown.ValueChangedFcn = createCallbackFcn(app, @SiteDropDownValueChanged, true);
            app.SiteDropDown.Value = 'None';
            app.YearDropDown = uidropdown(app.LeftUpperPanel);
            app.YearDropDown.Items = {'2999'};
            app.YearDropDown.ValueChangedFcn = createCallbackFcn(app, @YearDropDownValueChanged, true);
            app.YearDropDown.Value = '2999';
            app.FolderEditField = uieditfield(app.LeftUpperPanel, 'text');
            app.FolderEditField.ValueChangedFcn = createCallbackFcn(app, @FolderEditFieldValueChanged, true);
            app.FolderEditField.Tooltip = {'Type in database folder path.'};
            app.GetDirButton_1 = uibutton(app.LeftUpperPanel, 'push');
            app.GetDirButton_1.ButtonPushedFcn = createCallbackFcn(app, @GetDirButton_1Pushed, true);
            app.GetDirButton_1.Icon = 'Browse_16.png';
            app.GetDirButton_1.Tooltip = {'Select database folder.'};
            app.GetDirButton_1.Text = '';
            app.RightPanel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Subpanel = uipanel(app.RightPanel);
            app.Subpanel.SizeChangedFcn = createCallbackFcn(app, @SubpanelSizeChanged, true);
            app.TraceSpinnerLabel = uilabel(app.Subpanel);
            app.TraceSpinnerLabel.Text = 'Trace';
            app.TraceSpinner = uispinner(app.Subpanel);
            app.TraceSpinner.Limits = [1 2];
            app.TraceSpinner.ValueChangedFcn = createCallbackFcn(app, @TraceSpinnerValueChanged, true);
            app.TraceSpinner.Tooltip = {'Plot to [Summarize]: 1 - top; 2- bottom'};
            app.TraceSpinner.Value = 1;
            app.SummarizeButton = uibutton(app.Subpanel, 'push');
            app.SummarizeButton.ButtonPushedFcn = createCallbackFcn(app, @SummarizeButtonPushed, true);
            app.SummarizeButton.Visible = 'off';
            app.SummarizeButton.Tooltip = {'Basic summary plots for selected trace.'};
            app.SummarizeButton.Text = 'Summarize';
            app.CompareTraces = uibutton(app.Subpanel, 'push');
            app.CompareTraces.ButtonPushedFcn = createCallbackFcn(app, @CompareTracesPushed, true);
            app.CompareTraces.Visible = 'off';
            app.CompareTraces.Tooltip = {'Basic comparison plots between traces.'};
            app.CompareTraces.Text = 'Compare';
            app.CompareStages = uibutton(app.Subpanel, 'push');
            app.CompareStages.ButtonPushedFcn = createCallbackFcn(app, @CompareStagesPushed, true);
            app.CompareStages.Enable = 'off';
            app.CompareStages.Visible = 'off';
            app.CompareStages.Tooltip = {'Will run fr_compare_database_traces()'};
            app.CompareStages.Text = '';
            app.TraceanalysisLabel = uilabel(app.Subpanel);
            app.TraceanalysisLabel.Text = 'Trace analysis';
            app.TraceanalysisDropDown = uidropdown(app.Subpanel);
            app.TraceanalysisDropDown.Items = {'Choose', 'Single trace', 'Plot parents', 'Compare traces', 'Compare stages'};
            app.TraceanalysisDropDown.ValueChangedFcn = createCallbackFcn(app, @TraceanalysisDropDownValueChanged, true);
            app.TraceanalysisDropDown.Value = 'Choose';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end